classdef opto_analyser_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        opto_analyserUIFigure           matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ImageIOLabel                    matlab.ui.control.Label
        ReleasefileEditFieldLabel       matlab.ui.control.Label
        ReleasefileEditField            matlab.ui.control.EditField
        RecoveryfileEditFieldLabel      matlab.ui.control.Label
        RecoveryfileEditField           matlab.ui.control.EditField
        CellNameEditFieldLabel          matlab.ui.control.Label
        CellNameEditField               matlab.ui.control.EditField
        NoofintervalsLabel              matlab.ui.control.Label
        OKButton                        matlab.ui.control.StateButton
        ConfirmSelectionLabel           matlab.ui.control.Label
        ErrorMessagesLabel              matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        IntervalsselectedLabel          matlab.ui.control.Label
        RESETButton                     matlab.ui.control.StateButton
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ChannelselectionTab             matlab.ui.container.Tab
        Channel1Label                   matlab.ui.control.Label
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        Channel1ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_5                    matlab.ui.control.RadioButton
        MitochondriaButton              matlab.ui.control.RadioButton
        CytoplasmButton                 matlab.ui.control.RadioButton
        NucleusButton                   matlab.ui.control.RadioButton
        Channel2ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_6                    matlab.ui.control.RadioButton
        MitochondriaButton_2            matlab.ui.control.RadioButton
        CytoplasmButton_2               matlab.ui.control.RadioButton
        NucleusButton_2                 matlab.ui.control.RadioButton
        Channel3ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_7                    matlab.ui.control.RadioButton
        MitochondriaButton_3            matlab.ui.control.RadioButton
        CytoplasmButton_3               matlab.ui.control.RadioButton
        NucleusButton_3                 matlab.ui.control.RadioButton
        Channel4ButtonGroup             matlab.ui.container.ButtonGroup
        NoneButton_8                    matlab.ui.control.RadioButton
        MitochondriaButton_4            matlab.ui.control.RadioButton
        CytoplasmButton_4               matlab.ui.control.RadioButton
        NucleusButton_4                 matlab.ui.control.RadioButton
        AcceptChannelsandCropImageButton  matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        AcceptCropButton                matlab.ui.control.StateButton
        Channel2Label                   matlab.ui.control.Label
        Channel3Label                   matlab.ui.control.Label
        Channel4Label                   matlab.ui.control.Label
        CompartmentviewingpercentilesTab  matlab.ui.container.Tab
        LoadNextIterationButton         matlab.ui.control.StateButton
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        PercentageSliderLabel           matlab.ui.control.Label
        PercentageSlider                matlab.ui.control.Slider
        PercentageSlider_2Label         matlab.ui.control.Label
        PercentageSlider_2              matlab.ui.control.Slider
        NucleusLabel                    matlab.ui.control.Label
        MitochondriaLabel               matlab.ui.control.Label
        AcceptPercentilesforImageViewingButton  matlab.ui.control.StateButton
        FramewindowSliderLabel          matlab.ui.control.Label
        FramewindowSlider               matlab.ui.control.Slider
        FramewindowSlider_2Label        matlab.ui.control.Label
        FramewindowSlider_2             matlab.ui.control.Slider
        UIAxes7_3                       matlab.ui.control.UIAxes
        PercentageSlider_3Label         matlab.ui.control.Label
        PercentageSlider_3              matlab.ui.control.Slider
        FramewindowSlider_3Label        matlab.ui.control.Label
        FramewindowSlider_3             matlab.ui.control.Slider
        AllcompartmentsLabel            matlab.ui.control.Label
        CellboundarycropTab             matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
        UIAxes6_3                       matlab.ui.control.UIAxes
        ImageSelectionButtonGroup       matlab.ui.container.ButtonGroup
        NullButton_2                    matlab.ui.control.ToggleButton
        Image1Button                    matlab.ui.control.ToggleButton
        Image2Button                    matlab.ui.control.ToggleButton
        Image3Button                    matlab.ui.control.ToggleButton
        Image4Button                    matlab.ui.control.ToggleButton
        ConfirmcropButton               matlab.ui.control.StateButton
        UIAxes6_4                       matlab.ui.control.UIAxes
        AllframesLabel                  matlab.ui.control.Label
        RegionframesLabel               matlab.ui.control.Label
        MaxintensityallchannelsLabel    matlab.ui.control.Label
        MaxintensityallchannelsLabel_2  matlab.ui.control.Label
        MaxintensityproteinchannelLabel  matlab.ui.control.Label
        MaxintensityproteinchannelLabel_2  matlab.ui.control.Label
        RoughcropButton                 matlab.ui.control.Button
        CellthresholdingTab             matlab.ui.container.Tab
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes8_2                       matlab.ui.control.UIAxes
        UIAxes8_3                       matlab.ui.control.UIAxes
        Thresholdscale01SliderLabel     matlab.ui.control.Label
        Thresholdscale01Slider          matlab.ui.control.Slider
        Thresholdscale001SliderLabel    matlab.ui.control.Label
        Thresholdscale001Slider         matlab.ui.control.Slider
        Thresholdscale0001SliderLabel   matlab.ui.control.Label
        Thresholdscale0001Slider        matlab.ui.control.Slider
        Thresholdscale00001SliderLabel  matlab.ui.control.Label
        Thresholdscale00001Slider       matlab.ui.control.Slider
        Thresholdvalue000000Label       matlab.ui.control.Label
        AcceptthresholdButton           matlab.ui.control.Button
        CompartmentthresholdingTab      matlab.ui.container.Tab
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes10_2                      matlab.ui.control.UIAxes
        UIAxes10_3                      matlab.ui.control.UIAxes
        UIAxes10_4                      matlab.ui.control.UIAxes
        UIAxes10_5                      matlab.ui.control.UIAxes
        UIAxes10_6                      matlab.ui.control.UIAxes
        Mitochondriathresholdscale01SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale01Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale001Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale0001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale0001Slider  matlab.ui.control.Slider
        Mitochondriathresholdscale00001SliderLabel  matlab.ui.control.Label
        Mitochondriathresholdscale00001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale01SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale01Slider   matlab.ui.control.Slider
        Nucleusthresholdscale001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale0001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale0001Slider  matlab.ui.control.Slider
        Nucleusthresholdscale00001SliderLabel  matlab.ui.control.Label
        Nucleusthresholdscale00001Slider  matlab.ui.control.Slider
        Mitochondrialthresholdvalue000000Label  matlab.ui.control.Label
        AcceptthresholdsButton          matlab.ui.control.Button
        InterpolationTab                matlab.ui.container.Tab
        InterpolatedOutputLabel         matlab.ui.control.Label
        UIAxes11_5                      matlab.ui.control.UIAxes
        UIAxes11_6                      matlab.ui.control.UIAxes
        UIAxes11_7                      matlab.ui.control.UIAxes
        UIAxes11_8                      matlab.ui.control.UIAxes
        SavedataButton                  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        OutputLabel                     matlab.ui.control.Label
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes11_2                      matlab.ui.control.UIAxes
        UIAxes11_3                      matlab.ui.control.UIAxes
        UIAxes11_4                      matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        compartment_data %Structure to store all data for all window-splits 
        
        %Initial LSM input
        LSM_release % LSM release matrix 
        LSM_recovery %LSM recovery matrix
        total_channels %Total number of channels in the LSM
        LSM_vert_size %Vertical dimension of LSM frame
        LSM_horz_size %Horizontal dimension of LSM frame
        
        %Time info
        total_release_frames %Total number of frames in release
        total_recovery_frames %Total number of frames in recovery
        total_frames %Total number of frames 
        
        %Window info
        window_splits = []; %Vector of user determined intervals (window splits)
        window_stage % Keeps track of which window split or interval is currently being analysed.
        window_counter %Keeps track of which window in the window split is currently being analysed.
        
        %Channel info
        nuclear_channel %Tracks which channel has the nuclear label
        mitochondrial_channel %Tracks which channel has the mitochondrial label
        protein_channel %Tracks which channel has the protein label
        
        %LSM protein data
        LSM_protein_release %All protein frames of release
        LSM_protein_recovery %All protein frames of recovery
        LSM_protein_all_frames %All protein frames of the whole movie
        
        %ROI adjusted variables
        LSM_protein_frame1 %Protein channel frame 1. Used for zoom ROI
        vertical_min_ROI %Minimum vertical value of ROI
        vertical_max_ROI %Maximum vertical value of ROI
        horizontal_min_ROI %Minimum horizontal value of ROI
        horizontal_max_ROI %Maximum horizontal value of ROI
        LSM_nucleus_release_ROI %Zoomed in nuclear frames for release
        LSM_protein_release_ROI %Zoomed in protein frames for release
        LSM_mitochondria_release_ROI %Zoomed in mitochondrial frames for release
        LSM_nuclear_recovery_ROI %Zoomed in nuclear frames for recovery
        LSM_protein_recovery_ROI %Zoomed in protein frames for release
        LSM_mitochondria_recovery_ROI %Zoomed in mitochondrial frames for release
        LSM_nuclear_movie_ROI % All frames for nuclear channel (release and recovery) at ROI
        LSM_protein_movie_ROI % All frames for nuclear channel (release and recovery) at ROI
        LSM_mitochondria_movie_ROI % All frames for mitochondrial channel (release and recovery) at ROI
        
        %Maximum intensity projections of ROI
        max_int_release_frames_ROI %Frame by frame maximum intensity projection of the three channels in the ROI during release
        max_int_recovery_frames_ROI %Frame by frame maximum intensity projection of the three channels in the ROI during recovery
        max_int_release_all_ROI %Maximum intensity of all frames in max_int_release_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of release.
        max_int_recovery_all_ROI %Maximum intensity of all frames in max_int_recovery_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of recovery.
        max_int_all_movie_ROI %Maximum intensity of max_int_release_all_ROI and max_int_recovery_frames_ROI. I.e. this gives the maximum intensity over all channels and all frames of the whole movie.
        max_int_protein_all_movie_ROI %Maximum intensity of LSM_protein_release_ROI and LSM_protein_recovery_ROI. I.e. this gives the maximum intensity all frames of the protein channel.
        max_int_all_frames_ROI %Max intensity over all channels for each frame in the movie
        
        %Window splitting
        window_start_points %Start point for each window in the window split for the next iteration
        window_mid_points %Mid points for each window in the window split for the next iteration
        window_max_nucleus_ROI %Maximum intensity projection of nuclear channel over all frames in that window
        window_max_mitochondria_ROI %Maximum intensity projection of mitochondrial channel over all frames in that window
        window_max_protein_ROI %Maximum intensity projection of protein channel over all frames in that window
        window_max_all_channels_ROI %Maximum intensity projection of all channels over all frames in that window
        
        %Percentile allocation
        whole_cell_percentile %Percentile of intensity for the hybrid channel of maximum intensity of protein, mitochondria and nucleus combined for the given window-split
        nuclear_percentile %Percentile of intensity for the nuclear channel for the given window-split 
        mitochondria_percentile %Percentile of intensity for the mitochondrial channel for the given window-split 
        whole_cell_window_percentile_projection %Percentile projection of all frames in each window of the whole cell. Produces an array with z dimension equal to number of windows in that window split
        nucleus_window_percentile_projection %Percentile projection of all frames in each window of the nucleus. Produces an array with z dimension equal to number of windows in that window split
        mitochondria_window_percentile_projection
        whole_cell_window_index %Index of window in window-split that is currently being displayed for whole cell image. Index used for image display purposes only.
        nuclear_window_index %Index of window in window-split that is currently being displayed for nuclear image. Index used for image display purposes only.
        mitochondria_window_index %Index of window in window-split that is currently being displayed for mitochondrial image. Index used for image display purposes only.

        %Binary arrays
        rough_cell_boundary_window % Rough cell boundary for each window of the window-split, generated via user cropping.
        whole_cell_binary_window %Thresholded binary image for the whole cell for each window
        mitochondria_binary_window %Thresholded binary image for the mitochondria for each window
        nucleus_binary_window %Thresholded binary image for the nucleus for each window
        
        %Threshold values
        whole_cell_threshold %Overall threshold for whole cell in that window
        whole_cell_threshold_0_1 = 0 %Threshold contribution for 0.1 level slider for whole cell in that window
        whole_cell_threshold_0_01 = 0 %Threshold contribution for 0.01 level slider for whole cell in that window
        whole_cell_threshold_0_001 = 0 %Threshold contribution for 0.001 level slider for whole cell in that window
        whole_cell_threshold_0_0001 = 0 %Threshold contribution for 0.0001 level slider for whole cell in that window
        mitochondria_threshold %Overall threshold for mitochondria in that window
        mitochondria_threshold_0_1 = 0 %Threshold contribution for 0.1 level slider for mitochondria in that window
        mitochondria_threshold_0_01 = 0 %Threshold contribution for 0.01 level slider for mitochondria in that window
        mitochondria_threshold_0_001 = 0 %Threshold contribution for 0.001 level slider for mitochondria in that window
        mitochondria_threshold_0_0001 = 0 %Threshold contribution for 0.0001 level slider for mitochondria in that window
        nucleus_threshold %Overall threshold for mitochondria in that window
        nucleus_threshold_0_1 = 0 %Threshold contribution for 0.1 level slider for nucleus in that window
        nucleus_threshold_0_01 = 0 %Threshold contribution for 0.01 level slider for nucleus in that window
        nucleus_threshold_0_001 = 0 %Threshold contribution for 0.001 level slider for nucleus in that window
        nucleus_threshold_0_0001 = 0 %Threshold contribution for 0.0001 level slider for nucleus in that window
        whole_cell_thresholds %All threshold levels for whole cell for each windowin current window-split
        mitochondria_thresholds %Mitochondria threshold levels for whole cell for each windowin current window-split
        nucleus_thresholds %Nucleus threshold levels for whole cell for each windowin current window-split
        
        %Compartment populations versus time
        population_cytoplasm %Cytoplasmic total intensity versus time for current window-split
        population_mitochondria %Mitochondrial total intensity versus time for current window-split
        population_nucleus %Nuclear total intensity versus time for current window-split
        population_total %Whole cell total intensity versus time for current window-split
        historical_population_cytoplasm %Cytoplasmic total intensity versus time for all previously completed window-splits
        historical_population_mitochondria %Mitochondrial total intensity versus time for all previously completed window-splits
        historical_population_nucleus %Nuclear total intensity versus time for all previously completed window-splits
        historical_population_total %Whole cell total intensity versus time for all previously completed window-splits
        
        %Data table properties
        table_input %Input data for table
        table_background_colour %Store colourmap for each window in each window-split so row colours in table match plot colours
        
        %Interpolated data
        rough_cell_boundary_interpolated %Rough cell boundary interpolated over all frames from rough_cell_boundary_window
        whole_cell_thresholds_interp %Interpolated threshold levels for whole cell for every frame from whole_cell_thresholds
        mitochondria_thresholds_interp %Interpolated threshold levels for mitochondria for every frame from mitochondria_thresholds
        nucleus_thresholds_interp %Interpolated threshold levels for nucleus for every frame from nucleus_thresholds
        
        %Interpolation of percentile intensity projections from windows to
        %all frames. Done by taking a moving-percentile of number of frames
        %equal to window length and the percentile the relevant percentile
        %for that channel for every frame in movie.
        whole_cell_windows_interp % Interpolated percentile intensity projections for every frame for the whole cell.  
        nucleus_windows_interp % Interpolated percentile intensity projections for every frame for the nucleus.
        mitochondria_windows_interp % Interpolated percentile intensity projections for every frame for the mitochondria.
        
        %Error coding
        app_stage=0 % Only allows app functionality to be implemented in the correct order
        input_flag = 1 % flags whether files are both of lsm type (=1) or not (=0).
        error_counter = 0; %Keeps track of number of simultaneous errors
        cropping_stage = 0 %There are two cropping stages. 
        % cropping_stage==1 corresponds to rough cropping around the cell
        zoom_ROI_created_flag = 0; %Flag for creation of zoom ROI
        rough_cell_boundary_created = 0; %Flags whether the rough cell boundary has been created using the roipoly command
        whole_cell_threshold_slider_flag = 0 %Flags that the threshold slider for the whole cell has been adjusted by the user
        mitochondria_threshold_slider_flag = 0 %Flags that the threshold slider for the mitochondria has been adjusted by the user
        nucleus_threshold_slider_flag = 0 %Flags that the threshold slider for the nucleus has been adjusted by the user
        whole_cell_slider_percentile_flag %Flags that the percentile slider for the whole cell has been adjusted by the user
        nuclear_slider_percentile_flag %Flags that the percentile slider for the nucleus has been adjusted by the user
        mitochondria_slider_percentile_flag %Flags that the percentile slider for the mitochondria has been adjusted by the user
        %background_flag = 0; 
        %Normalisation
        %mean_background
    end
    
    methods (Access = private)
        
        function stage_error_counter = stage_error(app,current_stage,required_stage,cropping_stage)
        
            stage_error_counter = 0;
            if cropping_stage == 1
                info_label = 'Error Messages';
                lsm_type_error = 'Error. Complete square crop in new image window.';
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
                app.ErrorMessagesLabel.BackgroundColor = 'k';
            elseif cropping_stage == 2
                info_label = 'Error Messages';
                lsm_type_error = 'Error. Complete rough crop around cell in new image window.';
                stage_error_counter = 1;
                info_label = [info_label newline '(1)' lsm_type_error];
                app.ErrorMessagesLabel.Text = info_label;
                app.ErrorMessagesLabel.BackgroundColor = 'k';
            else
                if current_stage == 1 && required_stage ~= 1
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete I/O correctly.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 2 && required_stage ~= 2
                    info_label = 'Error Messages';
                    lsm_type_error = ['Error. Complete channel allocation and select' newline '"Accept Channels and Crop Image" in' newline 'Channel selection tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 3 && required_stage ~= 3
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Accept crop" in Channel selection tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 4 && required_stage ~= 4
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Load Next Iteration" in Compartment viewing percentiles tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 5 && required_stage ~= 5
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select desired percentiles for each channel in Compartment viewing percentiles tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 6 && required_stage ~= 6
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select best image and carry out rough cropping in percentiles for each compartment in Cell boundary crop tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                elseif current_stage == 7 && required_stage ~= 7
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Choose cell threshold and select "Accept threshold in Cell thresholding tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';    
                end      
        
            end

        end


        %Plotting functions
        function plot_imagesc(app,UIAxes,input_image)
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imagesc(input_image, 'Parent', UIAxes, ...
                'XData', [1 UIAxes.Position(3)], ...
                'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];
            axis(UIAxes, 'equal');
            
        end
        
        function plot_imshow(app,UIAxes,input_image)
            title(UIAxes, []);
            xlabel(UIAxes, []);
            ylabel(UIAxes, []);
            UIAxes.XAxis.TickLabels = {};
            UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            I = imshow(input_image, 'Parent', UIAxes, ...
                'XData', [1 UIAxes.Position(3)], ...
                'YData', [1 UIAxes.Position(4)]);
            % Set limits of axes
            UIAxes.XLim = [0 I.XData(2)];
            UIAxes.YLim = [0 I.YData(2)];
            axis(UIAxes, 'equal');
            
        end

        function intensity_plotting(app)
            cmap = jet(length(app.window_splits));
                if app.app_stage == 6
                    plot_function1 ='h=plot(app.UIAxes11,';
                    plot_function2 ='h=plot(app.UIAxes11_2,';
                    plot_function3 ='h=plot(app.UIAxes11_3,';
                    plot_function4 ='h=plot(app.UIAxes11_4,';
                    colour_function = 'set(h, {''color''}, {';
                    for J =1:app.window_stage-1
                        plot_function1 = [plot_function1,'1:app.total_frames,app.historical_population_mitochondria(:,' num2str(J) '),'];
                        plot_function2 = [plot_function2,'1:app.total_frames,app.historical_population_cytoplasm(:,' num2str(J) '),'];
                        plot_function3 = [plot_function3,'1:app.total_frames,app.historical_population_nucleus(:,' num2str(J) '),'];
                        plot_function4 = [plot_function4,'1:app.total_frames,app.historical_population_total(:,' num2str(J) '),'];
                        colour_function=[colour_function,'cmap(',num2str(J),',:);'];
                    end
                    plot_function1 = [plot_function1,'1:app.total_frames,app.population_mitochondria,''LineWidth'',2)'];
                    plot_function2 = [plot_function2,'1:app.total_frames,app.population_cytoplasm,''LineWidth'',2)'];
                    plot_function3 = [plot_function3,'1:app.total_frames,app.population_nucleus,''LineWidth'',2)'];
                    plot_function4 = [plot_function4,'1:app.total_frames,app.population_total,''LineWidth'',2)'];
                    colour_function=[colour_function,'cmap(app.window_stage,:)})']
                    
                    min_mito=nanmin(app.population_mitochondria);
                    max_mito=nanmax(app.population_mitochondria);
                    min_cyto=nanmin(app.population_cytoplasm)
                    max_cyto=nanmax(app.population_cytoplasm)
                    min_nuc=nanmin(app.population_nucleus);
                    max_nuc=nanmax(app.population_nucleus);
                    min_total=nanmin(app.population_total);
                    max_total=nanmax(app.population_total);
                    if  app.window_stage > 1
                        min_mito=min(nanmin(app.historical_population_mitochondria(:)),min_mito);
                        max_mito=max(nanmax(app.historical_population_mitochondria(:)),max_mito);
                        min_cyto=min(nanmin(app.historical_population_cytoplasm(:)),min_cyto);
                        max_cyto=max(nanmax(app.historical_population_cytoplasm(:)),max_cyto);
                        min_nuc=min(nanmin(app.historical_population_nucleus(:)),min_nuc);
                        max_nuc=max(nanmax(app.historical_population_nucleus(:)),max_nuc);
                        min_total=min(nanmin(app.historical_population_total(:)),min_total);
                        max_total=max(nanmax(app.historical_population_total(:)),max_total);
                    end
               else
                    plot_function1 ='h=plot(app.UIAxes11_5,';
                    plot_function2 ='h=plot(app.UIAxes11_6,';
                    plot_function3 ='h=plot(app.UIAxes11_7,';
                    plot_function4 ='h=plot(app.UIAxes11_8,';
                    colour_function = 'set(h, {''color''}, {';
                    for J =1:length(app.window_splits)
                        plot_function1 = [plot_function1,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_mito_population,'];
                        plot_function2 = [plot_function2,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_cytoplasm_population,'];
                        plot_function3 = [plot_function3,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_nucleus_population,'];
                        plot_function4 = [plot_function4,'1:app.total_frames,app.compartment_data(' num2str(J) ').interp_total_population,'];
                        colour_function=[colour_function,'cmap(',num2str(J),',:);'];
                        if J == 1
                            min_mito = nanmin(app.compartment_data(J).interp_mito_population);
                            max_mito = nanmax(app.compartment_data(J).interp_mito_population);
                            min_cyto = nanmin(app.compartment_data(J).interp_cytoplasm_population);
                            max_cyto = nanmax(app.compartment_data(J).interp_cytoplasm_population);
                            min_nuc = nanmin(app.compartment_data(J).interp_nucleus_population);
                            max_nuc = nanmax(app.compartment_data(J).interp_nucleus_population);
                            min_total = nanmin(app.compartment_data(J).interp_total_population);
                            max_total = nanmax(app.compartment_data(J).interp_total_population);
                        else
                            min_mito=min(min_mito,nanmin(app.compartment_data(J).interp_mito_population));
                            max_mito=max(max_mito,nanmax(app.compartment_data(J).interp_mito_population));
                            min_cyto=min(min_cyto,nanmin(app.compartment_data(J).interp_cytoplasm_population));
                            max_cyto=max(max_cyto,nanmax(app.compartment_data(J).interp_cytoplasm_population));
                            min_nuc=min(min_nuc,nanmin(app.compartment_data(J).interp_nucleus_population));
                            max_nuc=max(max_nuc,nanmax(app.compartment_data(J).interp_nucleus_population));
                            min_total=min(min_total,nanmin(app.compartment_data(J).interp_total_population));
                            max_total=max(max_total,nanmax(app.compartment_data(J).interp_total_population));
                        end
                    end
                    plot_function1 = [plot_function1,'''LineWidth'',2)'];
                    plot_function2 = [plot_function2,'''LineWidth'',2)'];
                    plot_function3 = [plot_function3,'''LineWidth'',2)'];
                    plot_function4 = [plot_function4,'''LineWidth'',2)'];
                    colour_function=[colour_function,'})']
                end
                eval(plot_function1);
                eval(colour_function);
                eval(plot_function2);
                eval(colour_function);
                eval(plot_function3);
                eval(colour_function);
                eval(plot_function4);
                eval(colour_function);
                
                if app.app_stage == 6
                    if min_mito < max_mito
                        ylim(app.UIAxes11,[min_mito max_mito]);
                    else
                        ylim(app.UIAxes11,[0 2]);
                    end
                    if min_cyto < max_cyto
                        ylim(app.UIAxes11_2,[min_cyto max_cyto]);
                    else
                        ylim(app.UIAxes11_2,[0 2]);
                    end
                    if min_nuc < max_nuc
                        ylim(app.UIAxes11_3,[min_nuc max_nuc]); 
                    else
                        ylim(app.UIAxes11_3,[0 2]);
                    end
                    if min_total < max_total
                        ylim(app.UIAxes11_4,[min_total max_total]); 
                    else
                        ylim(app.UIAxes11_4,[0 2]); 
                    end
                    
                else
                    if min_mito < max_mito
                        ylim(app.UIAxes11_5,[min_mito max_mito]);
                    else
                        ylim(app.UIAxes11_5,[0 2]);
                    end
                    if min_cyto < max_cyto
                        ylim(app.UIAxes11_6,[min_cyto max_cyto]);
                    else
                        ylim(app.UIAxes11_6,[0 2]);
                    end
                    if min_nuc < max_nuc
                        ylim(app.UIAxes11_7,[min_nuc max_nuc]); 
                    else
                        ylim(app.UIAxes11_7,[0 2]);
                    end
                    if min_total < max_total
                        ylim(app.UIAxes11_8,[min_total max_total]); 
                    else
                        ylim(app.UIAxes11_8,[0 2]); 
                    end
                end
                xlim(app.UIAxes11,[0 app.total_frames])
                xlim(app.UIAxes11_2,[0 app.total_frames])
                xlim(app.UIAxes11_3,[0 app.total_frames])
                xlim(app.UIAxes11_4,[0 app.total_frames])
                xlim(app.UIAxes11_5,[0 app.total_frames])
                xlim(app.UIAxes11_6,[0 app.total_frames])
                xlim(app.UIAxes11_7,[0 app.total_frames])
                xlim(app.UIAxes11_8,[0 app.total_frames])
                
                app.UIAxes11.XTickMode = 'auto';
                app.UIAxes11_2.XTickMode = 'auto';
                app.UIAxes11_3.XTickMode = 'auto';
                app.UIAxes11_4.XTickMode = 'auto';
                app.UIAxes11_5.XTickMode = 'auto';
                app.UIAxes11_6.XTickMode = 'auto';
                app.UIAxes11_7.XTickMode = 'auto';
                app.UIAxes11_8.XTickMode = 'auto';
        end

        %Thresholding functions
        function output_cell_bw = cell_threshold_sub_function(image_input,image_threshold_input,rough_cell_input)
           output_cell_bw=imbinarize(uint16(image_input),image_threshold_input);
           output_cell_bw = output_cell_bw.*rough_cell_input;
           output_cell_bw=imfill(output_cell_bw,'holes');
           SE = strel('disk', 1, 0);
           output_cell_bw=imopen(output_cell_bw,SE);
           L = bwlabel(output_cell_bw);
           LNo=length(unique(L(:)))-1;
           if(LNo>0)
               clear SizeI
               SizeI=zeros(1,LNo);
               for J=1:LNo
                   index=find(L==J);
                   SizeI(J)=length(index);
               end
               output_cell_bw=bwareaopen(output_cell_bw,max(SizeI),8);
           end
        end
        
        function output_mitochondria_bw = mitochondria_threshold_sub_function(image_input,image_threshold_input,cell_boundary_input)
            output_mitochondria_bw = imbinarize(uint16(image_input),image_threshold_input);
            output_mitochondria_bw=cell_boundary_input.*output_mitochondria_bw;
        end
        
        function output_nucleus_bw = nucleus_threshold_sub_function(image_input,image_threshold_input,cell_boundary_input,mito_boundary_input)
            output_nucleus_bw = imbinarize(uint16(image_input),image_threshold_input);
            nuc_cell_interval_image_bw=cell_boundary_input.*output_nucleus_bw;
            output_nucleus_bw=imfill(output_nucleus_bw,'holes');
            L = bwlabel(output_nucleus_bw);
            LNo=length(unique(L(:)))-1;
            if LNo>0
                nuc_object_area = zeros(1,LNo);
                for J=1:LNo
                    index_area=find(L==J);
                    nuc_object_area(J)=length(index_area);
                end
                output_nucleus_bw=bwareaopen(output_nucleus_bw,max(nuc_object_area),8);
            end
            overlap_bw=output_nucleus_bw.*mito_boundary_input;
            output_nucleus_bw(overlap_bw==1)=0;
            L = bwlabel(output_nucleus_bw);
            LNo=length(unique(L(:)))-1;
            if(LNo>0)
                nuc_object_area = zeros(1,LNo);
                for J=1:LNo
                    index=find(L==J);
                    nuc_object_area(J)=length(index);
                end
                output_nucleus_bw=bwareaopen(output_nucleus_bw,max(nuc_object_area),8);
            end
            
        end
        
        
        function [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.whole_cell_window_percentile_projection(:,:,app.window_counter);
            
            whole_cell_interval_image_bw = cell_threshold_sub_function(image_input,app.whole_cell_threshold,app.rough_cell_boundary_window(:,:,app.window_counter));
            
%             whole_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
%             whole_cell_interval_image_bw = whole_cell_interval_image_bw.*app.rough_cell_boundary_window(:,:,app.window_counter);
%             whole_cell_interval_image_bw=imfill(whole_cell_interval_image_bw,'holes');
%             SE = strel('disk', 1, 0);
%             whole_cell_interval_image_bw=imopen(whole_cell_interval_image_bw,SE);
%             L = bwlabel(whole_cell_interval_image_bw);
%             LNo=length(unique(L(:)))-1;
%             if(LNo>0)
%                 clear SizeI
%                 SizeI=zeros(1,LNo);
%                 for J=1:LNo
%                     index=find(L==J);
%                     SizeI(J)=length(index);
%                 end
%                 whole_cell_interval_image_bw=bwareaopen(whole_cell_interval_image_bw,max(SizeI),8);
%             end
            app.whole_cell_binary_window(:,:,app.window_counter)=whole_cell_interval_image_bw;
            whole_cell_interval_image_bw_skel=bwmorph(whole_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(whole_cell_interval_image_bw_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
        end


        function [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.mitochondria_window_percentile_projection(:,:,app.window_counter);
            
            mito_cell_interval_image_bw = mitochondria_threshold_sub_function(image_input,app.mitochondria_threshold,app.whole_cell_binary_window(:,:,app.window_counter));
%             
%           mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mitochondria_threshold);
%           mito_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.window_counter).*mito_cell_interval_image_bw;
            app.mitochondria_binary_window(:,:,app.window_counter)=mito_cell_interval_image_bw;
            mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
            
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(mito_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;
            
        end
        
        
        function [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw)
            image_input = zeros(size(app.max_int_all_movie_ROI));
            image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.window_counter);
            
            nuc_cell_interval_image_bw = nucleus_threshold_sub_function(image_input,app.nucleus_threshold,app.whole_cell_binary_window(:,:,app.window_counter),mito_cell_interval_image_bw);
%             
%             nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nucleus_threshold);
%             nuc_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.window_counter).*nuc_cell_interval_image_bw;
%             nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
%             L = bwlabel(nuc_cell_interval_image_bw);
%             LNo=length(unique(L(:)))-1;
%             if LNo>0
%                 nuc_object_area = zeros(1,LNo);
%                 for J=1:LNo
%                     index_area=find(L==J);
%                     nuc_object_area(J)=length(index_area);
%                 end
%                 nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
%             end
%             overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
%             nuc_cell_interval_image_bw(overlap_bw==1)=0;
%             L = bwlabel(nuc_cell_interval_image_bw);
%             LNo=length(unique(L(:)))-1;
%             if(LNo>0)
%                 nuc_object_area = zeros(1,LNo);
%                 for J=1:LNo
%                     index=find(L==J);
%                     nuc_object_area(J)=length(index);
%                 end
%                 nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
%             end
            app.nucleus_binary_window(:,:,app.window_counter)=nuc_cell_interval_image_bw;
            nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
            image_input=double(image_input-min(image_input(:)));
            image_input=image_input./max(image_input(:));
            image_input=uint16(round((2^16-1)*image_input));
            rgbcell = ind2rgb(image_input, parula(2^16));
            index=find(nuc_cell_interval_image_skel);
            channel=rgbcell(:,:,1);
            channel(index)=2^16-1;
            rgbcell(:,:,1)=channel;
            channel=rgbcell(:,:,2);
            channel(index)=2^16-1;
            rgbcell(:,:,2)=channel;
            channel=rgbcell(:,:,3);
            channel(index)=2^16-1;
            rgbcell(:,:,3)=channel;    
        end
        
        %Intensity extraction
        
        function app = population_intensity_extraction(app)
            if app.window_counter == app.window_splits(app.window_stage)
                current_frames = app.window_start_points(app.window_counter):app.total_frames;    
            else
                current_frames = app.window_start_points(app.window_counter):app.window_start_points(app.window_counter+1)-1;    
            end
        
            cell_object = app.whole_cell_binary_window(:,:,app.window_counter);
            nucleus_object = app.nucleus_binary_window(:,:,app.window_counter);
            mito_object = app.mitochondria_binary_window(:,:,app.window_counter);
            cytoplasm_object = max(cell_object - nucleus_object - mito_object, 0);
             
            for J = 1:length(current_frames)
                single_protein_frame(:,:) = app.LSM_protein_movie_ROI(:,:,current_frames(J));
                
                cytoplasm_population(current_frames(J)) = sum(single_protein_frame(cytoplasm_object > 0));
                mitochondria_population(current_frames(J)) = sum(single_protein_frame(mito_object > 0));
                nucleus_population(current_frames(J)) = sum(single_protein_frame(nucleus_object > 0));
            end
            app.population_cytoplasm(current_frames) = cytoplasm_population(current_frames);
            app.population_mitochondria(current_frames) = mitochondria_population(current_frames);
            app.population_nucleus(current_frames) = nucleus_population(current_frames);
            app.population_total(current_frames) = nucleus_population(current_frames) + mitochondria_population(current_frames) + cytoplasm_population(current_frames);
        end
        
        
        %Interpolation functions
        
        function app = interp_rough_region(app)
            if app.window_splits(app.window_stage)==1
                whole_region = app.rough_cell_boundary_window(:,:,1);
                app.rough_cell_boundary_interpolated = repmat(whole_region,[1,1,app.total_frames]);
            else
                first_region(:,:) = app.rough_cell_boundary_window(:,:,1);
                last_region(:,:) = app.rough_cell_boundary_window(:,:,end);
                start = repmat(first_region,[1,1,app.window_mid_points(1)]);
                finish = repmat(last_region,[1,1,app.total_frames-app.window_mid_points(end)+1]);
                interp_matrix = zeros([size(first_region),app.total_frames]);
                for I=1:length(app.window_mid_points)
                    interp_matrix(:,:,app.window_mid_points(I))=app.rough_cell_boundary_window(:,:,I);
                end
                for I=1:length(app.window_mid_points)-1
                   bw_r1(:,:)=app.rough_cell_boundary_window(:,:,I);
                   bw_r2(:,:)=app.rough_cell_boundary_window(:,:,I+1);
                   diff1=max(0,bw_r2-bw_r1);
                   diff2=max(0,bw_r1-bw_r2);
                   bw_dist_r1=bwdist(bw_r1);
                   bw_dist_r2=bwdist(bw_r2);
                   index1=find(diff1);
                   index2=find(diff2);
                   distance_r1=bw_dist_r1(index1);
                   distance_r1 = distance_r1/max(distance_r1);
                   distance_r1 = app.window_mid_points(I) + distance_r1*(app.window_mid_points(I+1)-app.window_mid_points(I));
                   distance_r1(distance_r1<app.window_mid_points(I)+1)=app.window_mid_points(I)+1;
                   distance_r1(distance_r1>app.window_mid_points(I+1)-1)=app.window_mid_points(I+1)-1;
                   distance_r1 = round(distance_r1);
                   bw_dist_r1 = zeros(size(bw_dist_r1));
                   bw_dist_r1(index1)=distance_r1;
                   distance_r2=bw_dist_r2(index2);
                   distance_r2 = 1-distance_r2/max(distance_r2);
                   distance_r2 = app.window_mid_points(I) + distance_r2*(app.window_mid_points(I+1)-app.window_mid_points(I));
                   distance_r2(distance_r2<app.window_mid_points(I)+1)=app.window_mid_points(I)+1;
                   distance_r2(distance_r2>app.window_mid_points(I+1)-1)=app.window_mid_points(I+1)-1;
                   distance_r2 = round(distance_r2);
                   bw_dist_r2 = zeros(size(bw_dist_r2));
                   bw_dist_r2(index2)=distance_r2;
                   
                   for J=app.window_mid_points(I)+1:app.window_mid_points(I+1)-1
                       index1 = find(bw_dist_r1 == J);
                       index2 = find(bw_dist_r2 == J);
                       input_matrix(:,:) = interp_matrix(:,:,J-1);
                       input_matrix(index1)=1;
                       input_matrix(index2)=0;
                       interp_matrix(:,:,J) = input_matrix;
                   end  
                end
                interp_matrix(:,:,1:app.window_mid_points(1))=start;
                interp_matrix(:,:,app.window_mid_points(end):end)=finish; 
                app.rough_cell_boundary_interpolated = interp_matrix;
            end
            
            
        end


        function app = interp_thresholds(app)
            if app.window_splits(app.window_stage)==1
                interp_data(1:app.total_frames) = app.whole_cell_thresholds;
                app.whole_cell_thresholds_interp = interp_data;
                
                interp_data(1:app.total_frames) = app.mitochondria_thresholds;
                app.mitochondria_thresholds_interp = interp_data;
                
                interp_data(1:app.total_frames) = app.nucleus_thresholds;
                app.nucleus_thresholds_interp = interp_data;
            else
                interp_data(1:app.window_mid_points(1))=app.whole_cell_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.whole_cell_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.whole_cell_thresholds(end);
                app.whole_cell_thresholds_interp = interp_data;
                
                interp_data(1:app.window_mid_points(1))=app.mitochondria_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.mitochondria_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.mitochondria_thresholds(end);
                app.mitochondria_thresholds_interp = interp_data;
                
                interp_data(1:app.window_mid_points(1))=app.nucleus_thresholds(1);
                interp_data(app.window_mid_points(1):app.window_mid_points(end))=interp1(app.window_mid_points,app.nucleus_thresholds,app.window_mid_points(1):app.window_mid_points(end));
                interp_data(app.window_mid_points(end):app.total_frames)=app.nucleus_thresholds(end);
                app.nucleus_thresholds_interp = interp_data;
            end

     
        end
        
        function app = interp_intensities(app)
            moving_average_distance = round(mean(diff(app.window_start_points)));
            moving_average_distance = moving_average_distance -1;
            moving_average_distance_max = ceil(moving_average_distance/2);
            moving_average_distance_min = floor(moving_average_distance/2);
            for J=1:app.total_frames
                if J - moving_average_distance_min <= 1
                    average_cell_window = app.max_int_all_frames_ROI(:,:,1:J+moving_average_distance_max);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,1:J+moving_average_distance_max);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,1:J+moving_average_distance_max);
                elseif J + moving_average_distance_min >= app.total_frames
                    average_cell_window = app.max_int_all_frames_ROI(:,:,J-moving_average_distance_max:end);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,J-moving_average_distance_max:end);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,J-moving_average_distance_max:end);
                else
                    average_cell_window = app.max_int_all_frames_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                    average_mito_window = app.LSM_mitochondria_movie_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                    average_nuc_window = app.LSM_nuclear_movie_ROI(:,:,J-moving_average_distance_max:J+moving_average_distance_min);
                end
                app.whole_cell_windows_interp(:,:,J) = uint16(percentile(average_cell_window,app.whole_cell_percentile,3));
                app.nucleus_windows_interp(:,:,J) = uint16(percentile(average_nuc_window,app.nuclear_percentile,3));
                app.mitochondria_windows_interp(:,:,J) = uint16(percentile(average_mito_window,app.mitochondria_percentile,3)); 
            end
      
        end


        function app = error_message_reset(app)
                app.ErrorMessagesLabel.BackgroundColor = 'none';
                app.error_counter = 0;
                app.ErrorMessagesLabel.Text = 'Error Messages';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.whole_cell_threshold=0;
            app.mitochondria_threshold=0;
            app.nucleus_threshold=0;
            app.app_stage=1;
            compartment_data = struct('window_start_points',{},'window_mid_points',{}, 'whole_cell_percentile',{},'nuclear_percentile',{},'mitochondria_percentile',{},...
                            'whole_cell_interval_image',{},'nucleus_window_percentile_projection',{},'mitochondria_window_percentile_projection',{},'rough_cell_boundary_window',{},...
                            'whole_cell_thresholds',{},'mitochondria_thresholds',{},'nucleus_thresholds',{},'whole_cell_binary_window',{},'mitochondria_binary_window',{},...
                            'nucleus_binary_window',{},'rough_cell_boundary_interpolated',{},'interp_whole_cell_threshold',{},'interp_mito_threshold',{},...
                            'interp_nucleus_threshold',{},'whole_cell_windows_interp',{},'nucleus_windows_interp',{},...
                            'mitochondria_windows_interp',{},'interp_total_pop',{},'interp_cyto_pop',{},'nucleus_population',{},'interp_mito_pop',{},...
                            'interp_cytoplasm_population',{},'interp_nucleus_population',{},'interp_mito_population',{},'interp_total_population',{}); 
            
            
        end

        % Value changed function: RESETButton
        function RESETButtonValueChanged(app, event)
            value = app.RESETButton.Value;
            app.app_stage=1;
            compartment_data = struct('window_start_points',{},'window_mid_points',{}, 'whole_cell_percentile',{},'nuclear_percentile',{},'mitochondria_percentile',{},...
                            'whole_cell_interval_image',{},'nucleus_window_percentile_projection',{},'mitochondria_window_percentile_projection',{},'rough_cell_boundary_window',{},...
                            'whole_cell_thresholds',{},'mitochondria_thresholds',{},'nucleus_thresholds',{},'whole_cell_binary_window',{},'mitochondria_binary_window',{},...
                            'nucleus_binary_window',{},'rough_cell_boundary_interpolated',{},'interp_whole_cell_threshold',{},'interp_mito_threshold',{},...
                            'interp_nucleus_threshold',{},'whole_cell_windows_interp',{},'nucleus_windows_interp',{},...
                            'mitochondria_windows_interp',{},'interp_total_pop',{},'interp_cyto_pop',{},'nucleus_population',{},'interp_mito_pop',{},...
                            'interp_cytoplasm_population',{},'interp_nucleus_population',{},'interp_mito_population',{},'interp_total_population',{});
            app = error_message_reset(app);            
        end

        % Value changed function: OKButton
        function OKButtonValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,1,app.cropping_stage);
            if stage_error_counter == 0
                app.input_flag = 1;
                app = error_message_reset(app);
                release_file=app.ReleasefileEditField.Value;
                recovery_file=app.RecoveryfileEditField.Value;
                
                if (isempty(release_file)) || (isempty(release_file)) || (length(release_file) < 3) || (length(recovery_file) < 3)
                   lsm_type_error = ['Error. Release and recovery' newline 'filename(s) cannot be empty.' newline 'There may be additional errors.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   %app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                   return
                end
                if(strcmp(release_file(end-2:end),'lsm')==0)||(strcmp(recovery_file(end-2:end),'lsm')==0)
                   lsm_type_error = ['Error. Image files must' newline 'be of lsm type.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   %app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if (isfile(release_file) == 0) || (isfile(recovery_file) == 0)
                    lsm_type_error = ['Error. At least one image' newline 'file does not exist.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    %app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if (isempty(app.CellNameEditField.Value))
                   lsm_type_error = ['Error. Cell Name cannot be empty.' newline 'There may be additional errors.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   %app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                   return
                end
                if (isempty(regexp(app.CellNameEditField.Value,'\W', 'once')) == 0)
                    lsm_type_error = ['Error. Cell name cannot include' newline 'spaces or special characters.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                interval_string = app.TextArea.Value;
                interval_string = interval_string{1}; 
                if (isempty(interval_string)) 
                    lsm_type_error = 'Error. No. of intervals cannot be empty.';
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    %app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                    return
                 end
                app.IntervalsselectedLabel.Text = 'Intervals selected: ';
                app.window_splits = [];
                print_intervals=1;
                value_split=strsplit(interval_string,',');
                for I=1:length(value_split)
                    value_num=str2double(value_split{I});
                    if(isempty(value_num))
                        lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                        print_intervals=0;
                        break
                    elseif (mod(value_num,1) ~= 0) ||  (value_num < 0)
                        lsm_type_error = ['Error. Intervals must be integers' newline 'greater than zero in' newline 'comma separated format.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        %app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                        print_intervals=0;
                        break
                    else
                        app.window_splits = [app.window_splits,value_num];    
                    end
                end
                if(isempty(app.window_splits) == 0) && (print_intervals == 1)
                    app.window_splits=unique(app.window_splits);
                    integer_string = sprintf('%.0f,' , app.window_splits);
                    integer_string = integer_string(1:end-1);% strip final comma
                    app.IntervalsselectedLabel.Text=[app.IntervalsselectedLabel.Text newline integer_string];
                end
                if exist('lsmread','file') ~=2
                    lsm_type_error = ['Error. m-file ''lsmread.m''' newline 'cannot be found.'];
                    app.input_flag = 0;
                    app.error_counter = app.error_counter +1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if (app.input_flag ==1)
                    %Add code to find lsmread
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Please wait. Loading data.','Indeterminate','on');
                    [app.LSM_release, ~] = lsmread(app.ReleasefileEditField.Value);
                    [app.total_release_frames,app.total_channels,~,app.LSM_vert_size,app.LSM_horz_size]=size(app.LSM_release);
                    [app.LSM_recovery, ~] = lsmread(app.RecoveryfileEditField.Value);
                    [app.total_recovery_frames,~,~,u_rec,v_rec]=size(app.LSM_recovery);
                    app.total_frames = app.total_release_frames + app.total_recovery_frames;
                    app.population_cytoplasm=zeros(app.total_frames,1)+NaN;
                    app.population_mitochondria=zeros(app.total_frames,1)+NaN;
                    app.population_nucleus=zeros(app.total_frames,1)+NaN;
                    app.population_total=zeros(app.total_frames,1)+NaN;
                    if app.total_channels~=3 && app.total_channels~=4
                        lsm_type_error = 'Error. No. of total_channels must be 3 or 4.';
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    elseif u_rec ~= app.LSM_vert_size || v_rec ~= app.LSM_horz_size
                        lsm_type_error = ['Error. Spatial dimensions of' newline 'release and recovery phases' newline 'should be the same.'];
                        app.input_flag = 0;
                        app.error_counter = app.error_counter +1;
                        info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                        app.ErrorMessagesLabel.Text = info_label;
                        app.ErrorMessagesLabel.BackgroundColor = 'k';
                    else
                        ch1(:, :) = app.LSM_release(1, 1, 1, :, :);
                        ch2(:, :) = app.LSM_release(1, 2, 1, :, :);
                        ch3(:, :) = app.LSM_release(1, 3, 1, :, :);
                        plot_imagesc(app,app.UIAxes,ch1)
                        plot_imagesc(app,app.UIAxes2,ch2)
                        plot_imagesc(app,app.UIAxes3,ch3)
                        if app.total_channels == 4
                            ch4(:, :) = app.LSM_release(1, 4, 1, :, :);
                            plot_imagesc(app,app.UIAxes4,ch4)
    
                        end
                             
                        app.app_stage=2;
                        cmap = jet(length(app.window_splits));
                        app.table_background_colour = [];
                        for J=1:length(app.window_splits)
                            for K=1:app.window_splits(J)
                                app.table_background_colour = [app.table_background_colour;cmap(J,:)];
                            end
                        end
                        app = error_message_reset(app);
                        %app.ErrorMessagesLabel.BackgroundColor = 'none';
                    end
                    close(d)
                    close(f)
                end
            end
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = uint8(min(max(round(event.Value),1),app.total_release_frames));
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                app = error_message_reset(app);
                ch1(:, :) = app.LSM_release(changingValue, 1, 1, :, :);
                ch2(:, :) = app.LSM_release(changingValue, 2, 1, :, :);
                ch3(:, :) = app.LSM_release(changingValue, 3, 1, :, :);
                plot_imagesc(app,app.UIAxes,ch1)
                plot_imagesc(app,app.UIAxes2,ch2)
                plot_imagesc(app,app.UIAxes3,ch3)
                if app.total_channels == 4
                    ch4(:, :) = app.LSM_release(changingValue, 4, 1, :, :);
                    plot_imagesc(app,app.UIAxes4,ch4)
                end
            end            
        end

        % Value changed function: AcceptChannelsandCropImageButton
        function AcceptChannelsandCropImageButtonValueChanged(app, event)
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0

                app = error_message_reset(app);
                app.input_flag = 1;
%                 app.ErrorMessagesLabel.BackgroundColor = 'none';
%                 app.error_counter = 0;
%                 app.ErrorMessagesLabel.Text = 'Error Messages';
%                 info_label = app.ErrorMessagesLabel.Text;
                if (app.NoneButton_8.Value == 0) && (app.total_channels == 3) 
                   lsm_type_error = ['Error. There are only three channels.' newline 'Channel 4 should be "None".'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                total_nucleus_radio = app.NucleusButton.Value + app.NucleusButton_2.Value + app.NucleusButton_3.Value + app.NucleusButton_4.Value;
                total_mitochondria_radio = app.MitochondriaButton.Value + app.MitochondriaButton_2.Value + app.MitochondriaButton_3.Value + app.MitochondriaButton_4.Value;
                total_cytoplasm_radio = app.CytoplasmButton.Value + app.CytoplasmButton_2.Value + app.CytoplasmButton_3.Value + app.CytoplasmButton_4.Value;
                if total_cytoplasm_radio == 2
                   lsm_type_error = ['Error. The app can analyse only one protein' newline 'at a time at present'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if total_nucleus_radio ~= 1 || total_mitochondria_radio ~= 1 || (total_cytoplasm_radio ~= 1 && total_cytoplasm_radio ~= 2)
                   lsm_type_error = ['Error. There must be one allocated' newline 'protein channel, nuclear channel and' newline 'mitochondrial channel.'];
                   app.input_flag = 0;
                   app.error_counter = app.error_counter +1;
                   app.ErrorMessagesLabel.Text = [app.ErrorMessagesLabel.Text newline '(' num2str(app.error_counter) ')' lsm_type_error];
                   %app.ErrorMessagesLabel.Text = info_label;
                   app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                
                if app.input_flag == 1
                    app = error_message_reset(app);
                    nucleus_location = [app.NucleusButton.Value, app.NucleusButton_2.Value, app.NucleusButton_3.Value, app.NucleusButton_4.Value];
                    protein_location = [app.CytoplasmButton.Value, app.CytoplasmButton_2.Value, app.CytoplasmButton_3.Value, app.CytoplasmButton_4.Value];
                    mitochondria_location = [app.MitochondriaButton.Value, app.MitochondriaButton_2.Value, app.MitochondriaButton_3.Value, app.MitochondriaButton_4.Value];
                    app.nuclear_channel = find(nucleus_location);
                    app.mitochondrial_channel = find(mitochondria_location);
                    app.protein_channel = find(protein_location);
                    LSM_protein_release=zeros(app.LSM_vert_size,app.LSM_horz_size,app.total_release_frames);
                    for I = 1:app.total_release_frames
                        LSM_protein_release(:, :, I)=app.LSM_release(I, app.protein_channel, 1, :, :);
                    end
                    app.LSM_protein_recovery=zeros(app.LSM_vert_size,app.LSM_horz_size,app.total_recovery_frames);
                    for I = 1:app.total_recovery_frames
                        app.LSM_protein_recovery(:, :, I)=app.LSM_recovery(I, app.protein_channel, 1, :, :);
                    end
                    app.LSM_protein_all_frames = cat(3, LSM_protein_release, app.LSM_protein_recovery);
                    %app.IntensityAll = sum(sum(app.LSM_protein_all_frames,1), 2);
                    app.LSM_protein_frame1(1:app.LSM_vert_size, 1:app.LSM_horz_size) = app.LSM_release(1,app.protein_channel,1,:,:);
                    
                    image_input = app.LSM_protein_frame1;
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                                        
                    crop_image = figure;
                    set(crop_image,'name','Zoom location','numbertitle','off')
                    imshow(rgbcell);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    app.cropping_stage = 1;
                    app.zoom_ROI_created_flag = 1;
                    while isvalid(crop_image)
                        h = drawrectangle;
                        while isvalid(h)
                           BW = createMask(h);
                           delete(h);    
                        end
                        
                        delete(crop_image);
                    end
                    %Draw a rectangle around image to get new image bounds
                              

                    if exist('BW','var')
                        index = find(BW);
                        [y, x] = ind2sub(size(BW),index);
    
                        max_x=max(x);
                        min_x=min(x);
                        max_y=max(y);
                        min_y=min(y);
                        y_length = max_y - min_y +1;
                        x_length = max_x - min_x +1;
                        [height,width]= size(BW);
                        if max(y_length,x_length) < min(height, width)
                            xy_diff = abs(y_length - x_length);
                            side1=floor(xy_diff/2)+xy_diff-2*floor(xy_diff/2);
                            side2=floor(xy_diff/2);
                            if x_length < y_length
                               upper_x = min(max_x+side1,width);
                               lower_x = max(min_x-side2,1);
                               if max_x+side1 > width
                                   lower_x = lower_x  - (upper_x - width);
                               end
                               if min_x-side2 < 1
                                   upper_x = upper_x + (1-min_x+side2);
                               end
                               lower_y=min_y;
                               upper_y=max_y;
                            elseif y_length < x_length
                                upper_y = min(max_y+side1,height);
                                lower_y = max(min_y-side2,1);
                                if max_y+side1 > height
                                    lower_y = lower_y  - (upper_y - height);
                                end
                                if min_y-side2 < 1
                                    upper_y = upper_y + (1-min_y+side2);
                                end
                                lower_x=min_x;
                                upper_x=max_x;
                             else
                                lower_y=min_y;
                                upper_y=max_y;
                                lower_x=min_x;
                                upper_x=max_y;
                            end
                            max_x = upper_x;
                            min_x = lower_x;
                            max_y = upper_y;
                            min_y = lower_y;
                        end
    
                        app.vertical_min_ROI = double(min_y);
                        app.vertical_max_ROI = double(max_y);
                        app.horizontal_min_ROI = double(min_x);
                        app.horizontal_max_ROI = double(max_x);
                        
                        %app.app_stage = 2;
                        app.cropping_stage = 0;
                        app.zoom_ROI_created_flag = 1;
                        app.ErrorMessagesLabel.BackgroundColor = 'none';                        
                    else
                        app.cropping_stage = 0;
                        app.zoom_ROI_created_flag = 0;
                    end

                end

            end

        end

        % Value changed function: AcceptCropButton
        function AcceptCropButtonValueChanged(app, event)
            %value = app.AcceptCropButton.Value;
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.zoom_ROI_created_flag ==0
                    info_label = 'Error Messages';
                    lsm_type_error = [' Error. Select "Accept channels and crop image"' newline 'in Channel selection tab.'];
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app)
                    %app.ErrorMessagesLabel.BackgroundColor = 'none';
                    for I=1:app.total_release_frames
                        app.LSM_nucleus_release_ROI(:,:,I) = app.LSM_release(I,app.nuclear_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                        app.LSM_protein_release_ROI(:,:,I) = app.LSM_release(I,app.protein_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                        app.LSM_mitochondria_release_ROI(:,:,I) = app.LSM_release(I,app.mitochondrial_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                    end
                    for I=1:app.total_recovery_frames
                        app.LSM_nuclear_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.nuclear_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                        app.LSM_protein_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.protein_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                        app.LSM_mitochondria_recovery_ROI(:,:,I) = app.LSM_recovery(I,app.mitochondrial_channel,1,app.vertical_min_ROI:app.vertical_max_ROI,app.horizontal_min_ROI:app.horizontal_max_ROI);
                    end
                    %Maximum of all release compartments (3d for time)
                    app.max_int_release_frames_ROI = max(max(app.LSM_nucleus_release_ROI,app.LSM_protein_release_ROI),app.LSM_mitochondria_release_ROI);
                    %Maximum of all recovery compartments (3d for time)
                    app.max_int_recovery_frames_ROI = max(max(app.LSM_nuclear_recovery_ROI,app.LSM_protein_recovery_ROI),app.LSM_mitochondria_recovery_ROI);
                    %2D maximum  of all release stages
                    app.max_int_release_all_ROI = max(app.max_int_release_frames_ROI,[],3);
                    %2D maximum of all recovery stages
                    app.max_int_recovery_all_ROI = max(app.max_int_recovery_frames_ROI,[],3);
                    %2D maximum of entire release and recovery movies
                    app.max_int_all_movie_ROI = max(app.max_int_release_all_ROI,app.max_int_recovery_all_ROI);
                    %Maximum of all compartment for every release and recovery
                    %frame
                    app.max_int_all_frames_ROI=cat(3,app.max_int_release_frames_ROI,app.max_int_recovery_frames_ROI);
                    app.max_int_protein_all_movie_ROI = max(max(app.LSM_protein_release_ROI,[],3),max(app.LSM_protein_recovery_ROI,[],3));
                    app.LSM_nuclear_movie_ROI=cat(3,app.LSM_nucleus_release_ROI,app.LSM_nuclear_recovery_ROI);
                    app.LSM_protein_movie_ROI=cat(3,app.LSM_protein_release_ROI,app.LSM_protein_recovery_ROI);
                    app.LSM_mitochondria_movie_ROI=cat(3,app.LSM_mitochondria_release_ROI,app.LSM_mitochondria_recovery_ROI);
                    
                    app.app_stage =3;
                    app.window_stage = 1;
                    app.window_counter = 1;
                    app.whole_cell_percentile=0;
                    app.nuclear_percentile=0;
                    app.mitochondria_percentile=0;
                    app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;
                  
                end

             end
        end

        % Value changed function: LoadNextIterationButton
        function LoadNextIterationButtonValueChanged(app, event)
            %value = app.LoadNextIterationButton.Value;
            stage_error_counter = stage_error(app,app.app_stage,3,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app)
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.whole_cell_slider_percentile_flag = 0;
                    app.nuclear_slider_percentile_flag = 0;
                    app.mitochondria_slider_percentile_flag = 0;
                    app.window_start_points = 1:(app.total_frames-1)/app.window_splits(app.window_stage):app.total_frames;%Start point of each region. 
                    app.window_start_points = round(app.window_start_points);
                    app.window_mid_points=1+(app.total_frames-1)/app.window_splits(app.window_stage)/2:(app.total_frames-1)/app.window_splits(app.window_stage):app.total_frames;
                    app.window_mid_points=round(app.window_mid_points);
                    app.window_max_all_channels_ROI=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.window_max_nucleus_ROI=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.window_max_mitochondria_ROI=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.window_max_protein_ROI=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    %app.MedianRegion=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
               
                    for J=1:app.window_splits(app.window_stage)
                        if J == app.window_splits(app.window_stage)
                            end_pt = app.window_start_points(J+1);
                        else
                            end_pt = app.window_start_points(J+1)-1;
                        end
                        %Median intensity for maximum intensities over time
                        %app.MedianRegion(:,:,J)=uint16(median(max(max(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(J):end_pt),app.LSM_protein_movie_ROI(:,:,app.window_start_points(J):end_pt)),app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(J):end_pt)),3));
                        %Maximum intensity of nucleus in that region
                        app.window_max_nucleus_ROI(:,:,J)=max(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intesnity of cytoplasm in that region
                        app.window_max_protein_ROI(:,:,J)=max(app.LSM_protein_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intensity of mitochondria in that region
                        app.window_max_mitochondria_ROI(:,:,J)=max(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(J):end_pt),[],3);
                        %Maximum intensity of entire cell over that time region
                        app.window_max_all_channels_ROI(:,:,J)=max(app.window_max_mitochondria_ROI(:,:,J),max(app.window_max_nucleus_ROI(:,:,J),app.window_max_protein_ROI(:,:,J)));
                    end
                    app.whole_cell_window_index=min(1,length(app.window_start_points)-1);
                    app.nuclear_window_index=min(1,length(app.window_start_points)-1);
                    app.mitochondria_window_index=min(1,length(app.window_start_points)-1);
                    whole_cell_image_percentile=uint16(percentile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    nuc_image_percentile=uint16(percentile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    mito_image_percentile=uint16(percentile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                   
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
                    app.app_stage = 4;
                    
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end

            end               


        end

        % Value changing function: FramewindowSlider_3
        function FramewindowSlider_3ValueChanging(app, event)
            changingValue = uint8(event.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app)
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.whole_cell_window_index=min(changingValue,length(app.window_start_points)-1);
                    whole_cell_image_percentile=uint16(percentile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end

            end            
        end

        % Value changing function: FramewindowSlider
        function FramewindowSliderValueChanging(app, event)
            changingValue = uint8(event.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app)
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.nuclear_window_index=min(changingValue,length(app.window_start_points)-1);
                    nuc_image_percentile=uint16(percentile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
              
            end
        end

        % Value changing function: FramewindowSlider_2
        function FramewindowSlider_2ValueChanging(app, event)
            changingValue = uint8(event.Value);
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app)
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.mitochondria_window_index=min(changingValue,length(app.window_start_points)-1);
                    mito_image_percentile=uint16(percentile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
               
            end               
        end

        % Value changing function: PercentageSlider_3
        function PercentageSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.whole_cell_slider_percentile_flag = 1;
                    app.whole_cell_percentile=changingValue;
                    whole_cell_image_percentile=uint16(percentile(app.max_int_all_frames_ROI(:,:,app.window_start_points(app.whole_cell_window_index):app.window_start_points(app.whole_cell_window_index+1)-1),app.whole_cell_percentile,3));
                    plot_imagesc(app,app.UIAxes7_3,whole_cell_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end

            end       
        end

        % Value changing function: PercentageSlider
        function PercentageSliderValueChanging(app, event)
           changingValue = event.Value;
           %changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app)
                %app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.nuclear_slider_percentile_flag = 1;
                    app.nuclear_percentile=changingValue;
                    nuc_image_percentile=uint16(percentile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(app.nuclear_window_index):app.window_start_points(app.nuclear_window_index+1)-1),app.nuclear_percentile,3));
                    plot_imagesc(app,app.UIAxes7,nuc_image_percentile)
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
            end                       
        end

        % Value changing function: PercentageSlider_2
        function PercentageSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.ErrorMessagesLabel.BackgroundColor = 'none';
                if app.window_counter == 1
                    app.mitochondria_slider_percentile_flag = 1;
                    app.mitochondria_percentile = changingValue;
                    mito_image_percentile = uint16(percentile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(app.mitochondria_window_index):app.window_start_points(app.mitochondria_window_index+1)-1),app.mitochondria_percentile,3));
                    plot_imagesc(app,app.UIAxes7_2,mito_image_percentile)
               else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = 1;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
             
            end     
        end

        % Value changed function: 
        % AcceptPercentilesforImageViewingButton
        function AcceptPercentilesforImageViewingButtonValueChanged(app, event)
            %value = app.AcceptPercentilesforImageViewingButton.Value;
            proceed_to_rough_cropping_flag = 1;
            app.error_counter = 0;
            info_label = 'Error Messages';
            stage_error_counter = stage_error(app,app.app_stage,4,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.window_counter ~= 1
                    lsm_type_error = 'Error. Complete stages in the previous interval selection first.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if app.whole_cell_slider_percentile_flag ~= 1 & app.whole_cell_percentile == 0
                    lsm_type_error = 'Error. Select nuclear imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end                 
                if app.nuclear_slider_percentile_flag ~= 1 & app.nuclear_percentile == 0
                    lsm_type_error = 'Error. Select nuclear imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end 
                if app.mitochondria_slider_percentile_flag ~= 1 & app.mitochondria_percentile == 0
                    lsm_type_error = 'Error. Select mitochondrial imaging percentile using the slider.';
                    app.error_counter = app.error_counter+ 1;
                    proceed_to_rough_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                if app.window_counter == 1 && proceed_to_rough_cropping_flag == 1
                    app = error_message_reset(app);
                    if app.window_splits(app.window_stage) ==1
                        image_input1 = zeros(size(app.max_int_all_movie_ROI));
                        image_input1(:,:) = app.max_int_all_movie_ROI;
                        image_input2 = zeros(size(app.max_int_all_movie_ROI));
                        image_input2(:,:) = app.max_int_protein_all_movie_ROI;
                    else
                        image_input1 = zeros(size(app.max_int_all_movie_ROI));
                        image_input1(:,:) = app.window_max_all_channels_ROI(:,:,app.window_counter);
                        image_input2 = zeros(size(app.max_int_all_movie_ROI));
                        image_input2(:,:) = app.window_max_protein_ROI(:,:,app.window_counter);
                    end
                    plot_imagesc(app,app.UIAxes6,image_input1)
                    plot_imagesc(app,app.UIAxes6_2,image_input2)
                    plot_imagesc(app,app.UIAxes6_3,app.max_int_all_movie_ROI)
                    plot_imagesc(app,app.UIAxes6_4,app.max_int_protein_all_movie_ROI)
                    app.whole_cell_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.nucleus_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    app.mitochondria_window_percentile_projection=zeros([size(app.max_int_all_movie_ROI),app.window_splits(app.window_stage)]);
                    for J=1:app.window_splits(app.window_stage)
                        if J == app.window_splits(app.window_stage)
                            end_pt = app.window_start_points(J+1);
                        else
                            end_pt = app.window_start_points(J+1)-1;
                        end
                        app.whole_cell_window_percentile_projection(:,:,J) = uint16(percentile(app.max_int_all_frames_ROI(:,:,app.window_start_points(J):end_pt),app.whole_cell_percentile,3));
                        app.nucleus_window_percentile_projection(:,:,J) = uint16(percentile(app.LSM_nuclear_movie_ROI(:,:,app.window_start_points(J):end_pt),app.nuclear_percentile,3));
                        app.mitochondria_window_percentile_projection(:,:,J) = uint16(percentile(app.LSM_mitochondria_movie_ROI(:,:,app.window_start_points(J):end_pt),app.mitochondria_percentile,3));
                    end
                    app.app_stage = 5;
                    app.rough_cell_boundary_created = 0;
                    app.NullButton_2.Value = true;
                    cla(app.UIAxes5);
                    app.TabGroup.SelectedTab = app.CellboundarycropTab;
                end
            end
        end

        % Selection changed function: ImageSelectionButtonGroup
        function ImageSelectionButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ImageSelectionButtonGroup.SelectedObject;
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.Image1Button.Value == true
                    if app.window_splits(app.window_stage) ==1
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.max_int_all_movie_ROI;
                    else
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.window_max_all_channels_ROI(:,:,app.window_counter);
                    end
                    plot_imagesc(app,app.UIAxes5,image_input)
                elseif app.Image2Button.Value == true
                    if app.window_splits(app.window_stage) ==1
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.max_int_protein_all_movie_ROI;
                    else
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) = app.window_max_protein_ROI(:,:,app.window_counter);
                    end
                    plot_imagesc(app,app.UIAxes5,image_input)
                elseif app.Image3Button.Value == true
                    plot_imagesc(app,app.UIAxes5,app.max_int_all_movie_ROI)
                elseif app.Image4Button.Value == true
                    plot_imagesc(app,app.UIAxes5,app.max_int_protein_all_movie_ROI)
                else
                    cla(app.UIAxes5)
                end
            end
        end

        % Button pushed function: RoughcropButton
        function RoughcropButtonPushed(app, event)
            %Place errors here for incorrectly timed press
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.NullButton_2.Value == true
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select best image for cropping cell outline.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app = error_message_reset(app);
                    app.ErrorMessagesLabel.BackgroundColor = 'none';
                    app.error_counter = 0;
                    app.ErrorMessagesLabel.Text = 'Error Messages';
                    info_label = app.ErrorMessagesLabel.Text;
                    if app.Image1Button.Value == true
                        if app.window_splits(app.window_stage) ==1
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.max_int_all_movie_ROI;
                        else
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.window_max_all_channels_ROI(:,:,app.window_counter);
                        end                
                    elseif app.Image2Button.Value == true
                        if app.window_splits(app.window_stage) ==1
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.max_int_protein_all_movie_ROI;
                        else
                            image_input = zeros(size(app.max_int_all_movie_ROI));
                            image_input(:,:) = app.window_max_protein_ROI(:,:,app.window_counter);
                        end
                    elseif app.Image3Button.Value == true
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) =  app.max_int_all_movie_ROI;
                    elseif app.Image4Button.Value == true
                        image_input = zeros(size(app.max_int_all_movie_ROI));
                        image_input(:,:) =  app.max_int_protein_all_movie_ROI;
                    end
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                    app.cropping_stage = 2; 
                    
                    rough_crop_image = figure;
                    set(rough_crop_image,'name','Rough crop','numbertitle','off')
                    imshow(rgbcell);
                    iptsetpref('ImshowAxesVisible','off')
                    axis('equal');
                    %imagesc(image_input);
                    iptsetpref('ImshowAxesVisible','off')
                    rough_window_bw = roipoly;
                    delete(rough_crop_image);
                    if exist('rough_window_bw','var')
                        app.rough_cell_boundary_window(:,:,app.window_counter) = rough_window_bw;
                        app.rough_cell_boundary_created = 1;
                        app.cropping_stage = 0;
                    end
                end
            end
            
            
        end

        % Value changed function: ConfirmcropButton
        function ConfirmcropButtonValueChanged(app, event)
            %value = app.ConfirmcropButton.Value;
            stage_error_counter = stage_error(app,app.app_stage,5,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.rough_cell_boundary_created == 1
                    %Add error checking
                    app.app_stage = 6;
                    app.whole_cell_threshold_slider_flag=0;
                    image_input = zeros(size(app.max_int_all_movie_ROI));
                    image_input(:,:) = app.whole_cell_window_percentile_projection(:,:,app.window_counter);
                    plot_imagesc(app,app.UIAxes8,image_input)
                    whole_cell_interval_image_bw=imbinarize(uint16(image_input),app.whole_cell_threshold);
                    whole_cell_interval_image_bw = whole_cell_interval_image_bw.*app.rough_cell_boundary_window(:,:,app.window_counter);
                    whole_cell_interval_image_bw=imfill(whole_cell_interval_image_bw,'holes');
                    SE = strel('disk', 1, 0);
                    whole_cell_interval_image_bw=imopen(whole_cell_interval_image_bw,SE);
                    L = bwlabel(whole_cell_interval_image_bw);
                    LNo=length(unique(L(:)))-1;
                    if(LNo>0)
                        clear SizeI
                        SizeI=zeros(1,LNo);
                        for J=1:LNo
                            index=find(L==J);
                            SizeI(J)=length(index);
                        end
                        whole_cell_interval_image_bw=bwareaopen(whole_cell_interval_image_bw,max(SizeI),8);
                    end
                    app.whole_cell_binary_window(:,:,app.window_counter)=whole_cell_interval_image_bw;
                    plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                    whole_cell_interval_image_bw=bwmorph(whole_cell_interval_image_bw,'remove');
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                    index=find(whole_cell_interval_image_bw);
                    channel=rgbcell(:,:,1);
                    channel(index)=2^16-1;
                    rgbcell(:,:,1)=channel;
                    channel=rgbcell(:,:,2);
                    channel(index)=2^16-1;
                    rgbcell(:,:,2)=channel;
                    channel=rgbcell(:,:,3);
                    channel(index)=2^16-1;
                    rgbcell(:,:,3)=channel;
                    plot_imshow(app,app.UIAxes8_3,rgbcell)
                    app.TabGroup.SelectedTab = app.CellthresholdingTab;
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select Rough Crop to outline cell.';
                    app.error_counter = app.error_counter+ 1;
                    %proceed_to_cropping_flag = 0;
                    info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                end
                
            end
        end

        % Value changed function: Thresholdscale01Slider
        function Thresholdscale01SliderValueChanged(app, event)
            value = app.Thresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_1 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end

        end

        % Value changed function: Thresholdscale001Slider
        function Thresholdscale001SliderValueChanged(app, event)
            value = app.Thresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_01 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
            else
               event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: Thresholdscale0001Slider
        function Thresholdscale0001SliderValueChanged(app, event)
            value = app.Thresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_001 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: Thresholdscale00001Slider
        function Thresholdscale00001SliderValueChanged(app, event)
            value = app.Thresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.whole_cell_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.whole_cell_threshold_0_0001 = value;
                app.whole_cell_threshold = app.whole_cell_threshold_0_1 + app.whole_cell_threshold_0_01 + app.whole_cell_threshold_0_001 + app.whole_cell_threshold_0_0001;
                [app,whole_cell_interval_image_bw,whole_cell_interval_image_bw_skel,rgbcell] = cell_threshold(app);
                plot_imshow(app,app.UIAxes8_2,whole_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes8_3,rgbcell)
                threshold_label = ['Threshold value:' newline num2str(app.whole_cell_threshold,'%.5f')];
                app.Thresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Button pushed function: AcceptthresholdButton
        function AcceptthresholdButtonPushed(app, event)
            stage_error_counter = stage_error(app,app.app_stage,6,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                if app.whole_cell_threshold_slider_flag == 1 || app.window_stage >1 || app.window_counter >1
                    app.app_stage = 7;
                    app.whole_cell_thresholds(app.window_counter) = app.whole_cell_threshold;
                    app.mitochondria_threshold_slider_flag=0;
                    app.nucleus_threshold_slider_flag=0;
                    image_input = zeros(size(app.max_int_all_movie_ROI));
                    image_input(:,:) = app.mitochondria_window_percentile_projection(:,:,app.window_counter);
                    plot_imagesc(app,app.UIAxes10,image_input)
                    mito_cell_interval_image_bw = imbinarize(uint16(image_input),app.mitochondria_threshold);
                    mito_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.window_counter).*mito_cell_interval_image_bw;
                    app.mitochondria_binary_window(:,:,app.window_counter)=mito_cell_interval_image_bw;
                    plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                    mito_cell_interval_image_skel=bwmorph(mito_cell_interval_image_bw,'remove');
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                    index=find(mito_cell_interval_image_skel);
                    channel=rgbcell(:,:,1);
                    channel(index)=2^16-1;
                    rgbcell(:,:,1)=channel;
                    channel=rgbcell(:,:,2);
                    channel(index)=2^16-1;
                    rgbcell(:,:,2)=channel;
                    channel=rgbcell(:,:,3);
                    channel(index)=2^16-1;
                    rgbcell(:,:,3)=channel;
                    plot_imshow(app,app.UIAxes10_3,rgbcell)
                    image_input = zeros(size(app.max_int_all_movie_ROI));
                    image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.window_counter);
                    plot_imagesc(app,app.UIAxes10_4,image_input)
                    nuc_cell_interval_image_bw = imbinarize(uint16(image_input),app.nucleus_threshold);
                    nuc_cell_interval_image_bw=app.whole_cell_binary_window(:,:,app.window_counter).*nuc_cell_interval_image_bw;
                    nuc_cell_interval_image_bw=imfill(nuc_cell_interval_image_bw,'holes');
                    clear nuc_object_area
                    L = bwlabel(nuc_cell_interval_image_bw);
                    LNo=length(unique(L(:)))-1;
                    if LNo>0
                        nuc_object_area = zeros(1,LNo);
                        for J=1:LNo
                            index_area=find(L==J);
                            nuc_object_area(J)=length(index_area);
                        end
                        nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
                    end
                    mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                    mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                    overlap_bw=nuc_cell_interval_image_bw.*mito_cell_interval_image_bw;
                    nuc_cell_interval_image_bw(overlap_bw==1)=0;
                    clear nuc_object_area
                    L = bwlabel(nuc_cell_interval_image_bw);
                    LNo=length(unique(L(:)))-1;
                    if(LNo>0)
                        for J=1:LNo
                            index=find(L==J);
                            nuc_object_area(J)=length(index);
                        end
                        nuc_cell_interval_image_bw=bwareaopen(nuc_cell_interval_image_bw,max(nuc_object_area),8);
                    end
                    app.nucleus_binary_window(:,:,app.window_counter)=nuc_cell_interval_image_bw;
                    plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                    nuc_cell_interval_image_skel=bwmorph(nuc_cell_interval_image_bw,'remove');
                    image_input=double(image_input-min(image_input(:)));
                    image_input=image_input./max(image_input(:));
                    image_input=uint16(round((2^16-1)*image_input));
                    rgbcell = ind2rgb(image_input, parula(2^16));
                    index=find(nuc_cell_interval_image_skel);
                    channel=rgbcell(:,:,1);
                    channel(index)=2^16-1;
                    rgbcell(:,:,1)=channel;
                    channel=rgbcell(:,:,2);
                    channel(index)=2^16-1;
                    rgbcell(:,:,2)=channel;
                    channel=rgbcell(:,:,3);
                    channel(index)=2^16-1;
                    rgbcell(:,:,3)=channel;
                    plot_imshow(app,app.UIAxes10_6,rgbcell)
                    
                    if app.window_stage >1 || app.window_counter >1
                        app = population_intensity_extraction(app);
                        intensity_plotting(app);
                    end
                    
                    
                    app.TabGroup.SelectedTab = app.CompartmentthresholdingTab;
                else
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Use sliders to select desired cell threshold.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';

                end
            end

        end

        % Value changed function: Mitochondriathresholdscale01Slider
        function Mitochondriathresholdscale01SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_1 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);        
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: 
        % Mitochondriathresholdscale001Slider
        function Mitochondriathresholdscale001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_01 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1); 
            end
        end

        % Value changed function: 
        % Mitochondriathresholdscale0001Slider
        function Mitochondriathresholdscale0001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_001 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end   
        end

        % Value changed function: 
        % Mitochondriathresholdscale00001Slider
        function Mitochondriathresholdscale00001SliderValueChanged(app, event)
            value = app.Mitochondriathresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.mitochondria_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.mitochondria_threshold_0_0001 = value;
                app.mitochondria_threshold = app.mitochondria_threshold_0_1 + app.mitochondria_threshold_0_01 + app.mitochondria_threshold_0_001 + app.mitochondria_threshold_0_0001;
                [app,mito_cell_interval_image_bw,rgbcell] = mitochondrial_threshold(app);
                plot_imshow(app,app.UIAxes10_2,mito_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_3,rgbcell)
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1);                
            end
        end

        % Value changed function: Nucleusthresholdscale01Slider
        function Nucleusthresholdscale01SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale01Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_1 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                image_input = zeros(size(app.max_int_all_movie_ROI));
                image_input(:,:) = app.nucleus_window_percentile_projection(:,:,app.window_counter);
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1);                
            end
        end

        % Value changed function: Nucleusthresholdscale001Slider
        function Nucleusthresholdscale001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_01 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: Nucleusthresholdscale0001Slider
        function Nucleusthresholdscale0001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale0001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_001 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Value changed function: Nucleusthresholdscale00001Slider
        function Nucleusthresholdscale00001SliderValueChanged(app, event)
            value = app.Nucleusthresholdscale00001Slider.Value;
            stage_error_counter = stage_error(app,app.app_stage,7,app.cropping_stage);
            if stage_error_counter == 0
                app = error_message_reset(app);
                app.nucleus_threshold_slider_flag=1;
                % determine which discrete option the current value is closest to.
                [min_major, minIdx_major] = min(abs(value - event.Source.MajorTicks(:)));
                [min_minor, minIdx_minor] = min(abs(value - event.Source.MinorTicks(:)));
                % move the slider to that option
                if min_major < min_minor
                   event.Source.Value = event.Source.MajorTicks(minIdx_major);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MajorTicks(minIdx_major); 
                else
                   event.Source.Value = event.Source.MinorTicks(minIdx_minor);
                   % Override the selected value if you plan on using it within this function
                   value = event.Source.MinorTicks(minIdx_minor);                 
                end
                app.nucleus_threshold_0_0001 = value;
                app.nucleus_threshold = app.nucleus_threshold_0_1 + app.nucleus_threshold_0_01 + app.nucleus_threshold_0_001 + app.nucleus_threshold_0_0001;
                mito_cell_interval_image_bw = zeros(size(app.max_int_all_movie_ROI));
                mito_cell_interval_image_bw(:,:) = app.mitochondria_binary_window(:,:,app.window_counter);
                [app,nuc_cell_interval_image_bw,rgbcell] = nuclear_threshold(app,mito_cell_interval_image_bw);
                plot_imshow(app,app.UIAxes10_5,nuc_cell_interval_image_bw)
                plot_imshow(app,app.UIAxes10_6,rgbcell)
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                threshold_label = ['Mitochondrial threshold value: '  num2str(app.mitochondria_threshold,'%.5f') newline 'Nucleus threshold value: ' num2str(app.nucleus_threshold,'%.5f')];
                app.Mitochondrialthresholdvalue000000Label.Text = threshold_label;
            else
                event.Source.Value = event.Source.MajorTicks(1);
            end
        end

        % Button pushed function: AcceptthresholdsButton
        function AcceptthresholdsButtonPushed(app, event)
            app.input_flag = 1;
            app = error_message_reset(app);
%             app.ErrorMessagesLabel.BackgroundColor = 'none';
%             app.error_counter = 0;
            info_label = 'Error Messages';
            if app.mitochondria_threshold_slider_flag == 0 && app.mitochondria_threshold ==0 
               lsm_type_error = ['Error. Select the mitochondrial threshold using the sliders.'];
               app.input_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.ErrorMessagesLabel.Text = info_label;
               app.ErrorMessagesLabel.BackgroundColor = 'k';
               return
            end
            if app.nucleus_threshold_slider_flag == 0 && app.nucleus_threshold ==0
               lsm_type_error = ['Error. Select the nuclear threshold using the sliders.'];
               app.input_flag = 0;
               app.error_counter = app.error_counter +1;
               info_label = [info_label newline '(' num2str(app.error_counter) ')' lsm_type_error];
               app.ErrorMessagesLabel.Text = info_label;
               app.ErrorMessagesLabel.BackgroundColor = 'k';
               return
            end   
        
            if app.input_flag ==1
                app = error_message_reset(app);
                app = population_intensity_extraction(app);
                intensity_plotting(app);
                app.mitochondria_thresholds(app.window_counter) = app.mitochondria_threshold;
                app.nucleus_thresholds(app.window_counter) = app.nucleus_threshold;
                input_data = {app.window_splits(app.window_stage) app.window_counter app.whole_cell_percentile app.nuclear_percentile app.mitochondria_percentile app.whole_cell_threshold app.mitochondria_threshold app.nucleus_threshold};
                %app.table_input = [app.table_input input_data];
                app.UITable.Data = [app.table_input; input_data];
                app.table_input = app.UITable.Data;
                app.UITable.BackgroundColor=app.table_background_colour;%[1 1 1 ;0.85 0.85 1];
                app.UITable.ForegroundColor=[0.5,0.5,0.5];

                %app.table_cell_percentile(app.table_counter) = ;
                if app.window_counter == app.window_splits(app.window_stage)
                    f = uifigure;
                    d = uiprogressdlg(f,'Title','Please wait. Interpolating.','Indeterminate','on');
                    app = interp_rough_region(app);
                    app = interp_thresholds(app);
                    app = interp_intensities(app);
                    clear interp_cytoplasm_population interp_nucleus_population interp_mito_population interp_total_population
                    for J=1:app.total_frames
                        frame_cell = zeros(size(app.max_int_all_movie_ROI));
                        frame_cell(:,:) = app.whole_cell_windows_interp(:,:,J);
                        whole_cell_frame_image_bw = cell_threshold_sub_function(frame_cell,app.whole_cell_thresholds_interp(J),app.rough_cell_boundary_interpolated(:,:,J));
%                         
%                         whole_cell_frame_image_bw=imbinarize(uint16(frame_cell),app.whole_cell_thresholds_interp(J));
%                         whole_cell_frame_image_bw = whole_cell_frame_image_bw.*app.rough_cell_boundary_interpolated(:,:,J);
%                         whole_cell_frame_image_bw=imfill(whole_cell_frame_image_bw,'holes');
%                         SE = strel('disk', 1, 0);
%                         whole_cell_frame_image_bw=imopen(whole_cell_frame_image_bw,SE);
%                         L = bwlabel(whole_cell_frame_image_bw);
%                         LNo=length(unique(L(:)))-1;
%                         if(LNo>0)
%                             clear SizeI
%                             SizeI=zeros(1,LNo);
%                             for K=1:LNo
%                                 index=find(L==K);
%                                 SizeI(K)=length(index);
%                             end
%                             whole_cell_frame_image_bw=bwareaopen(whole_cell_frame_image_bw,max(SizeI),8);
%                         end


                        frame_mito = zeros(size(app.max_int_all_movie_ROI));
                        frame_mito(:,:) = app.mitochondria_windows_interp(:,:,J);
                        mito_cell_frame_image_bw = mitochondria_threshold_sub_function(frame_mito,app.mitochondria_thresholds_interp(J),whole_cell_frame_image_bw);
                        
%                         mito_cell_frame_image_bw = imbinarize(uint16(frame_mito),app.mitochondria_thresholds_interp(J));
%                         mito_cell_frame_image_bw=whole_cell_frame_image_bw.*mito_cell_frame_image_bw;

                        frame_nuc = zeros(size(app.max_int_all_movie_ROI));
                        frame_nuc(:,:) = app.nucleus_windows_interp(:,:,J);
                        nuc_cell_frame_image_bw = nucleus_threshold_sub_function(frame_nuc,app.nucleus_thresholds_interp(J),whole_cell_frame_image_bw,mito_cell_frame_image_bw);
                        
%                         nuc_cell_frame_image_bw = imbinarize(uint16(frame_nuc),app.nucleus_thresholds_interp(J));
%                         nuc_cell_frame_image_bw=whole_cell_frame_image_bw.*nuc_cell_frame_image_bw;
%                         nuc_cell_frame_image_bw=imfill(nuc_cell_frame_image_bw,'holes');
%                         L = bwlabel(nuc_cell_frame_image_bw);
%                         LNo=length(unique(L(:)))-1;
%                         if LNo>0
%                             nuc_object_area = zeros(1,LNo);
%                             for K=1:LNo
%                                 index_area=find(L==K);
%                                 nuc_object_area(K)=length(index_area);
%                             end
%                             nuc_cell_frame_image_bw=bwareaopen(nuc_cell_frame_image_bw,max(nuc_object_area),8);
%                         end
%                         overlap_bw=nuc_cell_frame_image_bw.*mito_cell_frame_image_bw;
%                         nuc_cell_frame_image_bw(overlap_bw==1)=0;
%                         L = bwlabel(nuc_cell_frame_image_bw);
%                         LNo=length(unique(L(:)))-1;
%                         if(LNo>0)
%                             nuc_object_area = zeros(1,LNo);
%                             for K=1:LNo
%                                 index=find(L==K);
%                                 nuc_object_area(K)=length(index);
%                             end
%                             nuc_cell_frame_image_bw=bwareaopen(nuc_cell_frame_image_bw,max(nuc_object_area),8);
%                         end

                        cytoplasm_cell_frame_image_bw = max(whole_cell_frame_image_bw - nuc_cell_frame_image_bw - mito_cell_frame_image_bw,0);
                        single_protein_frame(:,:) = app.LSM_protein_movie_ROI(:,:,J);
                        protein_input = single_protein_frame(cytoplasm_cell_frame_image_bw > 0);
                        interp_cytoplasm_population(J) = sum(protein_input(:));
                        protein_input = single_protein_frame(nuc_cell_frame_image_bw > 0);
                        interp_nucleus_population(J) = sum(protein_input(:));
                        protein_input = single_protein_frame(mito_cell_frame_image_bw > 0);
                        interp_mito_population(J) = sum(protein_input(:));
                        interp_total_population(J)=interp_cytoplasm_population(J)+interp_nucleus_population(J)+interp_mito_population(J);
                        
                    end

                    app.compartment_data(app.window_stage).window_start_points=app.window_start_points;
                    app.compartment_data(app.window_stage).window_mid_points=app.window_mid_points;
                    app.compartment_data(app.window_stage).whole_cell_percentile=app.whole_cell_percentile;
                    app.compartment_data(app.window_stage).nuclear_percentile=app.nuclear_percentile;
                    app.compartment_data(app.window_stage).mitochondria_percentile=app.mitochondria_percentile;
                    app.compartment_data(app.window_stage).whole_cell_interval_image=app.whole_cell_window_percentile_projection;
                    app.compartment_data(app.window_stage).nucleus_window_percentile_projection=app.nucleus_window_percentile_projection;
                    app.compartment_data(app.window_stage).mitochondria_window_percentile_projection=app.mitochondria_window_percentile_projection;
                    app.compartment_data(app.window_stage).rough_cell_boundary_window=app.rough_cell_boundary_window;
                    app.compartment_data(app.window_stage).whole_cell_thresholds=app.whole_cell_thresholds;
                    app.compartment_data(app.window_stage).mitochondria_thresholds=app.mitochondria_thresholds;
                    app.compartment_data(app.window_stage).nucleus_thresholds=app.nucleus_thresholds;
                    app.compartment_data(app.window_stage).whole_cell_binary_window=app.whole_cell_binary_window;
                    app.compartment_data(app.window_stage).mitochondria_binary_window=app.mitochondria_binary_window;
                    app.compartment_data(app.window_stage).nucleus_binary_window=app.nucleus_binary_window;
                    app.compartment_data(app.window_stage).rough_cell_boundary_interpolated=app.rough_cell_boundary_interpolated;
                    app.compartment_data(app.window_stage).interp_whole_cell_threshold=app.whole_cell_thresholds_interp;
                    app.compartment_data(app.window_stage).interp_mito_threshold=app.mitochondria_thresholds_interp;
                    app.compartment_data(app.window_stage).interp_nucleus_threshold=app.nucleus_thresholds_interp;
                    app.compartment_data(app.window_stage).whole_cell_windows_interp=app.whole_cell_windows_interp;
                    app.compartment_data(app.window_stage).nucleus_windows_interp=app.nucleus_windows_interp;
                    app.compartment_data(app.window_stage).mitochondria_windows_interp=app.mitochondria_windows_interp;
                    app.compartment_data(app.window_stage).interp_cytoplasm_population=interp_cytoplasm_population;
                    app.compartment_data(app.window_stage).interp_nucleus_population=interp_nucleus_population;
                    app.compartment_data(app.window_stage).interp_mito_population=interp_mito_population;
                    app.compartment_data(app.window_stage).interp_total_population=interp_total_population;
                    app.historical_population_cytoplasm(:,app.window_stage) = app.population_cytoplasm;
                    app.historical_population_mitochondria(:,app.window_stage) =app.population_mitochondria;
                    app.historical_population_nucleus(:,app.window_stage) =app.population_nucleus;
                    app.historical_population_total(:,app.window_stage) =app.population_total;

                    close(d);
                    close(f);
                    
                    if app.window_stage == length(app.window_splits) 
                        app.app_stage =8;
                        intensity_plotting(app);
                        app.TabGroup.SelectedTab = app.InterpolationTab;
                                         
                    %Also save global variables below
%                     intervals
%                     total_release_frames
%                     total_recovery_frames
%                     total_frames
%                     total_channels
%                     LSM_nuclear_movie_ROI
%                     LSM_protein_movie_ROI
%                     LSM_mitochondria_movie_ROI
%                     vertical_min_ROI
%                     vertical_max_ROI
%                     horizontal_min_ROI
%                     horizontal_max_ROI
                        
                    else
                        app.window_stage = app.window_stage + 1;
                        app.window_counter = 1;
                        app.whole_cell_threshold_slider_flag=0;
                        app.population_cytoplasm=zeros(app.total_frames,1)+NaN;
                        app.population_mitochondria=zeros(app.total_frames,1)+NaN;
                        app.population_nucleus=zeros(app.total_frames,1)+NaN;
                        app.population_total=zeros(app.total_frames,1)+NaN;
                        cla(app.UIAxes7_3)
                        cla(app.UIAxes7)
                        cla(app.UIAxes7_2)
                        app.app_stage =3;
                        app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;
                    end
                elseif app.window_counter < app.window_splits(app.window_stage)    
                    app.window_counter = app.window_counter +1;
                    app.whole_cell_threshold_slider_flag=0;
                    image_input1 = zeros(size(app.max_int_all_movie_ROI));
                    image_input1(:,:) = app.window_max_all_channels_ROI(:,:,app.window_counter);
                    image_input2 = zeros(size(app.max_int_all_movie_ROI));
                    image_input2(:,:) = app.window_max_protein_ROI(:,:,app.window_counter);
                    plot_imagesc(app,app.UIAxes6,image_input1)
                    plot_imagesc(app,app.UIAxes6_2,image_input2)
                    plot_imagesc(app,app.UIAxes6_3,app.max_int_all_movie_ROI)
                    plot_imagesc(app,app.UIAxes6_4,app.max_int_protein_all_movie_ROI)
                    app.app_stage = 5;
                    app.rough_cell_boundary_created = 0;
                    app.NullButton_2.Value = true;
                    cla(app.UIAxes5);
                    app.TabGroup.SelectedTab = app.CellboundarycropTab;
                else
                    %Errors here
                end

            end
            
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            f = uifigure;
            d = uiprogressdlg(f,'Title','Saving and closing. Goodbye.','Indeterminate','on');
%             save_folder = app.ReleasefileEditField.Value;
%             dash_locs = strfind(save_folder,'\');
%             save_folder = save_folder(1:dash_locs(end));
%             save_folder = [save_folder app.CellNameEditField.Value '\']
%             mkdir(save_folder)
            
            
            name = 'opto_analyser_output.mat';
            [fnm,pth] = uiputfile(name);
            if ischar(fnm)
                compartment_data = app.compartment_data;
                save(fullfile(pth,fnm),compartment_data)
            end
            close(d);
            close(f);
            delete(app)
%             
%             
%             
%             filename = [save_folder 'opto_analyser_output.mat'];
%             save(filename,'app');
  
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.opto_analyserUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {567, 567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x', 414};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Callback function
        function AcceptBackgroundButtonValueChanged(app, event)
            
            stage_error_counter = stage_error(app,app.app_stage,2,app.cropping_stage);
            if stage_error_counter == 0
                if app.background_flag ==0
                    info_label = 'Error Messages';
                    lsm_type_error = 'Error. Select "Accept crop and select background" in Channel selection tab.';
                    stage_error_counter = 1;
                    info_label = [info_label newline '(1)' lsm_type_error];
                    app.ErrorMessagesLabel.Text = info_label;
                    app.ErrorMessagesLabel.BackgroundColor = 'k';
                else
                    app.app_stage =3;
                    app.window_stage = 1;
                    app.window_counter = 1;
                    app.whole_cell_percentile=0;
                    app.nuclear_percentile=0;
                    app.mitochondria_percentile=0;
                    app.TabGroup.SelectedTab = app.CompartmentviewingpercentilesTab;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.opto_analyserUIFigure = uifigure('Visible', 'off');
            app.opto_analyserUIFigure.Name = 'opto_analyser';
            app.opto_analyserUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.opto_analyserUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImageIOLabel = uilabel(app.LeftPanel);
            app.ImageIOLabel.Text = 'Image I/O';
            app.ReleasefileEditFieldLabel = uilabel(app.LeftPanel);
            app.ReleasefileEditFieldLabel.Text = 'Release file';
            app.ReleasefileEditField = uieditfield(app.LeftPanel, 'text');
            app.ReleasefileEditField.Value = 'C:\Documents\release.lsm';
            app.RecoveryfileEditFieldLabel = uilabel(app.LeftPanel);
            app.RecoveryfileEditFieldLabel.Text = 'Recovery file';
            app.RecoveryfileEditField = uieditfield(app.LeftPanel, 'text');
            app.RecoveryfileEditField.Value = 'C:\Documents\recovery.lsm';
            app.CellNameEditFieldLabel = uilabel(app.LeftPanel);
            app.CellNameEditFieldLabel.Text = 'Cell Name';
            app.CellNameEditField = uieditfield(app.LeftPanel, 'text');
            app.CellNameEditField.Value = 'cell_name';
            app.NoofintervalsLabel = uilabel(app.LeftPanel);
            app.NoofintervalsLabel.Text = 'No. of intervals';
            app.OKButton = uibutton(app.LeftPanel, 'state');
            app.OKButton.ValueChangedFcn = createCallbackFcn(app, @OKButtonValueChanged, true);
            app.OKButton.Text = 'OK';
            app.ConfirmSelectionLabel = uilabel(app.LeftPanel);
            app.ConfirmSelectionLabel.Text = 'Confirm Selection';
            app.ErrorMessagesLabel = uilabel(app.LeftPanel);
            app.ErrorMessagesLabel.Text = 'Error Messages';
            app.TextArea = uitextarea(app.LeftPanel);
            app.IntervalsselectedLabel = uilabel(app.LeftPanel);
            app.IntervalsselectedLabel.Text = 'Intervals selected: ';
            app.RESETButton = uibutton(app.LeftPanel, 'state');
            app.RESETButton.ValueChangedFcn = createCallbackFcn(app, @RESETButtonValueChanged, true);
            app.RESETButton.Text = 'RESET';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.ChannelselectionTab = uitab(app.TabGroup);
            app.ChannelselectionTab.Title = 'Channel selection';
            app.Channel1Label = uilabel(app.ChannelselectionTab);
            app.Channel1Label.Text = '  Channel 1';
            app.FrameSliderLabel = uilabel(app.ChannelselectionTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.ChannelselectionTab);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.Channel1ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.NoneButton_5 = uiradiobutton(app.Channel1ButtonGroup);
            app.NoneButton_5.Text = 'None';
            app.NoneButton_5.Value = true;
            app.MitochondriaButton = uiradiobutton(app.Channel1ButtonGroup);
            app.MitochondriaButton.Text = 'Mitochondria';
            app.CytoplasmButton = uiradiobutton(app.Channel1ButtonGroup);
            app.CytoplasmButton.Text = 'Cytoplasm';
            app.NucleusButton = uiradiobutton(app.Channel1ButtonGroup);
            app.NucleusButton.Text = 'Nucleus';
            app.Channel2ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel2ButtonGroup.Title = 'Channel 2';
            app.NoneButton_6 = uiradiobutton(app.Channel2ButtonGroup);
            app.NoneButton_6.Text = 'None';
            app.NoneButton_6.Value = true;
            app.MitochondriaButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.MitochondriaButton_2.Text = 'Mitochondria';
            app.CytoplasmButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.CytoplasmButton_2.Text = 'Cytoplasm';
            app.NucleusButton_2 = uiradiobutton(app.Channel2ButtonGroup);
            app.NucleusButton_2.Text = 'Nucleus';
            app.Channel3ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel3ButtonGroup.Title = 'Channel 3';
            app.NoneButton_7 = uiradiobutton(app.Channel3ButtonGroup);
            app.NoneButton_7.Text = 'None';
            app.NoneButton_7.Value = true;
            app.MitochondriaButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.MitochondriaButton_3.Text = 'Mitochondria';
            app.CytoplasmButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.CytoplasmButton_3.Text = 'Cytoplasm';
            app.NucleusButton_3 = uiradiobutton(app.Channel3ButtonGroup);
            app.NucleusButton_3.Text = 'Nucleus';
            app.Channel4ButtonGroup = uibuttongroup(app.ChannelselectionTab);
            app.Channel4ButtonGroup.Title = 'Channel 4';
            app.NoneButton_8 = uiradiobutton(app.Channel4ButtonGroup);
            app.NoneButton_8.Text = 'None';
            app.NoneButton_8.Value = true;
            app.MitochondriaButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.MitochondriaButton_4.Text = 'Mitochondria';
            app.CytoplasmButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.CytoplasmButton_4.Text = 'Cytoplasm';
            app.NucleusButton_4 = uiradiobutton(app.Channel4ButtonGroup);
            app.NucleusButton_4.Text = 'Nucleus';
            app.AcceptChannelsandCropImageButton = uibutton(app.ChannelselectionTab, 'state');
            app.AcceptChannelsandCropImageButton.ValueChangedFcn = createCallbackFcn(app, @AcceptChannelsandCropImageButtonValueChanged, true);
            app.AcceptChannelsandCropImageButton.Text = 'Accept Channels and Crop Image';
            app.UIAxes = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.ChannelselectionTab);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.AcceptCropButton = uibutton(app.ChannelselectionTab, 'state');
            app.AcceptCropButton.ValueChangedFcn = createCallbackFcn(app, @AcceptCropButtonValueChanged, true);
            app.AcceptCropButton.Text = 'Accept Crop';
            app.Channel2Label = uilabel(app.ChannelselectionTab);
            app.Channel2Label.Text = '  Channel 2';
            app.Channel3Label = uilabel(app.ChannelselectionTab);
            app.Channel3Label.Text = '  Channel 3';
            app.Channel4Label = uilabel(app.ChannelselectionTab);
            app.Channel4Label.Text = '  Channel 4';
            app.CompartmentviewingpercentilesTab = uitab(app.TabGroup);
            app.CompartmentviewingpercentilesTab.Title = 'Compartment viewing percentiles';
            app.LoadNextIterationButton = uibutton(app.CompartmentviewingpercentilesTab, 'state');
            app.LoadNextIterationButton.ValueChangedFcn = createCallbackFcn(app, @LoadNextIterationButtonValueChanged, true);
            app.LoadNextIterationButton.Text = 'Load Next Iteration';
            app.UIAxes7 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7, '')
            xlabel(app.UIAxes7, '')
            ylabel(app.UIAxes7, '')
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.UIAxes7_2 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_2, '')
            xlabel(app.UIAxes7_2, '')
            ylabel(app.UIAxes7_2, '')
            app.UIAxes7_2.XTick = [];
            app.UIAxes7_2.YTick = [];
            app.PercentageSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSliderLabel.Text = 'Percentage';
            app.PercentageSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider.ValueChangingFcn = createCallbackFcn(app, @PercentageSliderValueChanging, true);
            app.PercentageSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_2Label.Text = 'Percentage';
            app.PercentageSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_2.ValueChangingFcn = createCallbackFcn(app, @PercentageSlider_2ValueChanging, true);
            app.NucleusLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.NucleusLabel.Text = 'Nucleus';
            app.MitochondriaLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.MitochondriaLabel.Text = 'Mitochondria';
            app.AcceptPercentilesforImageViewingButton = uibutton(app.CompartmentviewingpercentilesTab, 'state');
            app.AcceptPercentilesforImageViewingButton.ValueChangedFcn = createCallbackFcn(app, @AcceptPercentilesforImageViewingButtonValueChanged, true);
            app.AcceptPercentilesforImageViewingButton.Text = 'Accept Percentiles for Image Viewing';
            app.FramewindowSliderLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSliderLabel.Text = 'Frame window';
            app.FramewindowSlider = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider.Limits = [1 10];
            app.FramewindowSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider.ValueChangingFcn = createCallbackFcn(app, @FramewindowSliderValueChanging, true);
            app.FramewindowSlider.MinorTicks = [];
            app.FramewindowSlider.Value = 1;
            app.FramewindowSlider_2Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_2Label.Text = 'Frame window';
            app.FramewindowSlider_2 = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_2.Limits = [1 10];
            app.FramewindowSlider_2.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider_2.ValueChangingFcn = createCallbackFcn(app, @FramewindowSlider_2ValueChanging, true);
            app.FramewindowSlider_2.MinorTicks = [];
            app.FramewindowSlider_2.Value = 1;
            app.UIAxes7_3 = uiaxes(app.CompartmentviewingpercentilesTab);
            title(app.UIAxes7_3, '')
            xlabel(app.UIAxes7_3, '')
            ylabel(app.UIAxes7_3, '')
            app.UIAxes7_3.XTick = [];
            app.UIAxes7_3.YTick = [];
            app.PercentageSlider_3Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_3Label.Text = 'Percentage';
            app.PercentageSlider_3 = uislider(app.CompartmentviewingpercentilesTab);
            app.PercentageSlider_3.ValueChangingFcn = createCallbackFcn(app, @PercentageSlider_3ValueChanging, true);
            app.FramewindowSlider_3Label = uilabel(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_3Label.Text = 'Frame window';
            app.FramewindowSlider_3 = uislider(app.CompartmentviewingpercentilesTab);
            app.FramewindowSlider_3.Limits = [1 10];
            app.FramewindowSlider_3.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.FramewindowSlider_3.ValueChangingFcn = createCallbackFcn(app, @FramewindowSlider_3ValueChanging, true);
            app.FramewindowSlider_3.MinorTicks = [];
            app.FramewindowSlider_3.Value = 1;
            app.AllcompartmentsLabel = uilabel(app.CompartmentviewingpercentilesTab);
            app.AllcompartmentsLabel.Text = 'All compartments';
            app.CellboundarycropTab = uitab(app.TabGroup);
            app.CellboundarycropTab.Title = 'Cell boundary crop';
            app.UIAxes5 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes5, '')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6, '')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.UIAxes6_2 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_2, '')
            xlabel(app.UIAxes6_2, '')
            ylabel(app.UIAxes6_2, '')
            app.UIAxes6_2.XTick = [];
            app.UIAxes6_2.YTick = [];
            app.UIAxes6_3 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_3, '')
            xlabel(app.UIAxes6_3, '')
            ylabel(app.UIAxes6_3, '')
            app.UIAxes6_3.XTick = [];
            app.UIAxes6_3.YTick = [];
            app.ImageSelectionButtonGroup = uibuttongroup(app.CellboundarycropTab);
            app.ImageSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageSelectionButtonGroupSelectionChanged, true);
            app.ImageSelectionButtonGroup.Title = 'Image Selection';
            app.NullButton_2 = uitogglebutton(app.ImageSelectionButtonGroup);
            app.NullButton_2.Text = 'Null';
            app.NullButton_2.Value = true;
            app.Image1Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image1Button.Text = 'Image 1';
            app.Image2Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image2Button.Text = 'Image 2';
            app.Image3Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image3Button.Text = 'Image 3';
            app.Image4Button = uitogglebutton(app.ImageSelectionButtonGroup);
            app.Image4Button.Text = 'Image 4';
            app.ConfirmcropButton = uibutton(app.CellboundarycropTab, 'state');
            app.ConfirmcropButton.ValueChangedFcn = createCallbackFcn(app, @ConfirmcropButtonValueChanged, true);
            app.ConfirmcropButton.Text = 'Confirm crop';
            app.UIAxes6_4 = uiaxes(app.CellboundarycropTab);
            title(app.UIAxes6_4, '')
            xlabel(app.UIAxes6_4, '')
            ylabel(app.UIAxes6_4, '')
            app.UIAxes6_4.XTick = [];
            app.UIAxes6_4.YTick = [];
            app.AllframesLabel = uilabel(app.CellboundarycropTab);
            app.AllframesLabel.Text = 'All frames';
            app.RegionframesLabel = uilabel(app.CellboundarycropTab);
            app.RegionframesLabel.Text = 'Region frames';
            app.MaxintensityallchannelsLabel = uilabel(app.CellboundarycropTab);
            app.MaxintensityallchannelsLabel.Text = {'Max intensity '; '- all channels'};
            app.MaxintensityallchannelsLabel_2 = uilabel(app.CellboundarycropTab);
            app.MaxintensityallchannelsLabel_2.Text = {'Max intensity '; '- all channels'};
            app.MaxintensityproteinchannelLabel = uilabel(app.CellboundarycropTab);
            app.MaxintensityproteinchannelLabel.Text = {'Max intensity '; '- protein channel'};
            app.MaxintensityproteinchannelLabel_2 = uilabel(app.CellboundarycropTab);
            app.MaxintensityproteinchannelLabel_2.Text = {'Max intensity '; '- protein channel'};
            app.RoughcropButton = uibutton(app.CellboundarycropTab, 'push');
            app.RoughcropButton.ButtonPushedFcn = createCallbackFcn(app, @RoughcropButtonPushed, true);
            app.RoughcropButton.Text = 'Rough crop';
            app.CellthresholdingTab = uitab(app.TabGroup);
            app.CellthresholdingTab.Title = 'Cell thresholding';
            app.UIAxes8 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8, '')
            xlabel(app.UIAxes8, '')
            ylabel(app.UIAxes8, '')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8_2 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8_2, '')
            xlabel(app.UIAxes8_2, '')
            ylabel(app.UIAxes8_2, '')
            app.UIAxes8_2.XTick = [];
            app.UIAxes8_2.YTick = [];
            app.UIAxes8_3 = uiaxes(app.CellthresholdingTab);
            title(app.UIAxes8_3, '')
            xlabel(app.UIAxes8_3, '')
            ylabel(app.UIAxes8_3, '')
            app.UIAxes8_3.XTick = [];
            app.UIAxes8_3.YTick = [];
            app.Thresholdscale01SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale01SliderLabel.Text = 'Threshold scale 0.1';
            app.Thresholdscale01Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale01Slider.Limits = [0 0.9];
            app.Thresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Thresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale01SliderValueChanged, true);
            app.Thresholdscale01Slider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.7 0.8];
            app.Thresholdscale001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale001SliderLabel.Text = 'Threshold scale 0.01';
            app.Thresholdscale001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale001Slider.Limits = [0 0.09];
            app.Thresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Thresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale001SliderValueChanged, true);
            app.Thresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Thresholdscale0001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale0001SliderLabel.Text = 'Threshold scale 0.001';
            app.Thresholdscale0001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale0001Slider.Limits = [0 0.009];
            app.Thresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Thresholdscale0001Slider.MajorTickLabels = {'0', '0.003', '0.006', '0.009', ''};
            app.Thresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale0001SliderValueChanged, true);
            app.Thresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Thresholdscale00001SliderLabel = uilabel(app.CellthresholdingTab);
            app.Thresholdscale00001SliderLabel.Text = 'Threshold scale 0.0001';
            app.Thresholdscale00001Slider = uislider(app.CellthresholdingTab);
            app.Thresholdscale00001Slider.Limits = [0 0.0009];
            app.Thresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Thresholdscale00001Slider.MajorTickLabels = {'0', '0.0003', '0.0006', '0.0009', ''};
            app.Thresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Thresholdscale00001SliderValueChanged, true);
            app.Thresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Thresholdvalue000000Label = uilabel(app.CellthresholdingTab);
            app.Thresholdvalue000000Label.Text = {'Threshold value:'; '0.00000'};
            app.AcceptthresholdButton = uibutton(app.CellthresholdingTab, 'push');
            app.AcceptthresholdButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptthresholdButtonPushed, true);
            app.AcceptthresholdButton.Text = 'Accept threshold';
            app.CompartmentthresholdingTab = uitab(app.TabGroup);
            app.CompartmentthresholdingTab.Title = 'Compartment thresholding';
            app.UIAxes9 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes9, '')
            xlabel(app.UIAxes9, '')
            ylabel(app.UIAxes9, '')
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UIAxes10 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10, '')
            xlabel(app.UIAxes10, '')
            ylabel(app.UIAxes10, '')
            app.UIAxes10.XTick = [];
            app.UIAxes10.YTick = [];
            app.UIAxes10_2 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_2, '')
            xlabel(app.UIAxes10_2, '')
            ylabel(app.UIAxes10_2, '')
            app.UIAxes10_2.XTick = [];
            app.UIAxes10_2.YTick = [];
            app.UIAxes10_3 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_3, '')
            xlabel(app.UIAxes10_3, '')
            ylabel(app.UIAxes10_3, '')
            app.UIAxes10_3.XTick = [];
            app.UIAxes10_3.YTick = [];
            app.UIAxes10_4 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_4, '')
            xlabel(app.UIAxes10_4, '')
            ylabel(app.UIAxes10_4, '')
            app.UIAxes10_4.XTick = [];
            app.UIAxes10_4.YTick = [];
            app.UIAxes10_5 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_5, '')
            xlabel(app.UIAxes10_5, '')
            ylabel(app.UIAxes10_5, '')
            app.UIAxes10_5.XTick = [];
            app.UIAxes10_5.YTick = [];
            app.UIAxes10_6 = uiaxes(app.CompartmentthresholdingTab);
            title(app.UIAxes10_6, '')
            xlabel(app.UIAxes10_6, '')
            ylabel(app.UIAxes10_6, '')
            app.UIAxes10_6.XTick = [];
            app.UIAxes10_6.YTick = [];
            app.Mitochondriathresholdscale01SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale01SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.1'};
            app.Mitochondriathresholdscale01Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale01Slider.Limits = [0 0.9];
            app.Mitochondriathresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Mitochondriathresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale01SliderValueChanged, true);
            app.Mitochondriathresholdscale01Slider.MinorTicks = [0.1 0.2 0.4 0.5 0.7 0.8];
            app.Mitochondriathresholdscale001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale001SliderLabel.Text = {'Mitochondria '; 'threshold scale 0.01'};
            app.Mitochondriathresholdscale001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale001Slider.Limits = [0 0.09];
            app.Mitochondriathresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Mitochondriathresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale001SliderValueChanged, true);
            app.Mitochondriathresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Mitochondriathresholdscale0001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale0001SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.001'};
            app.Mitochondriathresholdscale0001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale0001Slider.Limits = [0 0.009];
            app.Mitochondriathresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Mitochondriathresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale0001SliderValueChanged, true);
            app.Mitochondriathresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Mitochondriathresholdscale00001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale00001SliderLabel.Text = {'Mitochondria'; 'threshold scale 0.0001'};
            app.Mitochondriathresholdscale00001Slider = uislider(app.CompartmentthresholdingTab);
            app.Mitochondriathresholdscale00001Slider.Limits = [0 0.0009];
            app.Mitochondriathresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Mitochondriathresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Mitochondriathresholdscale00001SliderValueChanged, true);
            app.Mitochondriathresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Nucleusthresholdscale01SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale01SliderLabel.Text = {'Nucleus'; 'threshold scale 0.1'};
            app.Nucleusthresholdscale01Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale01Slider.Limits = [0 0.9];
            app.Nucleusthresholdscale01Slider.MajorTicks = [0 0.3 0.6 0.9];
            app.Nucleusthresholdscale01Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale01SliderValueChanged, true);
            app.Nucleusthresholdscale01Slider.MinorTicks = [0.1 0.2 0.4 0.5 0.7 0.8];
            app.Nucleusthresholdscale001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale001SliderLabel.Text = {'Nucleus'; 'threshold scale 0.01'};
            app.Nucleusthresholdscale001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale001Slider.Limits = [0 0.09];
            app.Nucleusthresholdscale001Slider.MajorTicks = [0 0.03 0.06 0.09];
            app.Nucleusthresholdscale001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale001SliderValueChanged, true);
            app.Nucleusthresholdscale001Slider.MinorTicks = [0.01 0.02 0.04 0.05 0.07 0.08];
            app.Nucleusthresholdscale0001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale0001SliderLabel.Text = {'Nucleus'; 'threshold scale 0.001'};
            app.Nucleusthresholdscale0001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale0001Slider.Limits = [0 0.009];
            app.Nucleusthresholdscale0001Slider.MajorTicks = [0 0.003 0.006 0.009];
            app.Nucleusthresholdscale0001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale0001SliderValueChanged, true);
            app.Nucleusthresholdscale0001Slider.MinorTicks = [0.001 0.002 0.004 0.005 0.007 0.008];
            app.Nucleusthresholdscale00001SliderLabel = uilabel(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale00001SliderLabel.Text = {'Nucleus '; 'threshold scale 0.0001'};
            app.Nucleusthresholdscale00001Slider = uislider(app.CompartmentthresholdingTab);
            app.Nucleusthresholdscale00001Slider.Limits = [0 0.0009];
            app.Nucleusthresholdscale00001Slider.MajorTicks = [0 0.0003 0.0006 0.0009];
            app.Nucleusthresholdscale00001Slider.ValueChangedFcn = createCallbackFcn(app, @Nucleusthresholdscale00001SliderValueChanged, true);
            app.Nucleusthresholdscale00001Slider.MinorTicks = [0.0001 0.0002 0.0004 0.0005 0.0007 0.0008];
            app.Mitochondrialthresholdvalue000000Label = uilabel(app.CompartmentthresholdingTab);
            app.Mitochondrialthresholdvalue000000Label.Text = {'Mitochondrial threshold value: 0.00000'; 'Nucleus threshold value: 0.00000'};
            app.AcceptthresholdsButton = uibutton(app.CompartmentthresholdingTab, 'push');
            app.AcceptthresholdsButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptthresholdsButtonPushed, true);
            app.AcceptthresholdsButton.Text = 'Accept thresholds';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Interpolation';
            app.InterpolatedOutputLabel = uilabel(app.InterpolationTab);
            app.InterpolatedOutputLabel.Text = 'Interpolated Output';
            app.UIAxes11_5 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_5, 'Mitochondria')
            xlabel(app.UIAxes11_5, 'Frame number')
            ylabel(app.UIAxes11_5, 'Intensity')
            app.UIAxes11_5.XLim = [0 100];
            app.UIAxes11_5.YLim = [0 2];
            app.UIAxes11_5.XTick = [0 50 100];
            app.UIAxes11_6 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_6, 'Cytoplasm')
            xlabel(app.UIAxes11_6, 'Frame number')
            ylabel(app.UIAxes11_6, 'Intensity')
            app.UIAxes11_6.XLim = [0 100];
            app.UIAxes11_6.YLim = [0 2];
            app.UIAxes11_6.XTick = [0 50 100];
            app.UIAxes11_7 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_7, 'Nucleus')
            xlabel(app.UIAxes11_7, 'Frame number')
            ylabel(app.UIAxes11_7, 'Intensity')
            app.UIAxes11_7.XLim = [0 100];
            app.UIAxes11_7.YLim = [0 2];
            app.UIAxes11_7.XTick = [0 50 100];
            app.UIAxes11_8 = uiaxes(app.InterpolationTab);
            title(app.UIAxes11_8, 'Total')
            xlabel(app.UIAxes11_8, 'Frame number')
            ylabel(app.UIAxes11_8, 'Intensity')
            app.UIAxes11_8.XLim = [0 100];
            app.UIAxes11_8.YLim = [0 2];
            app.UIAxes11_8.XTick = [0 50 100];
            app.SavedataButton = uibutton(app.InterpolationTab, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            app.RightPanel = uipanel(app.GridLayout);
            app.OutputLabel = uilabel(app.RightPanel);
            app.OutputLabel.Text = 'Output';
            app.UIAxes11 = uiaxes(app.RightPanel);
            title(app.UIAxes11, 'Mitochondria')
            xlabel(app.UIAxes11, 'Frame number')
            ylabel(app.UIAxes11, 'Intensity')
            app.UIAxes11.XLim = [0 100];
            app.UIAxes11.YLim = [0 2];
            app.UIAxes11.XTick = [0 50 100];
            app.UIAxes11_2 = uiaxes(app.RightPanel);
            title(app.UIAxes11_2, 'Cytoplasm')
            xlabel(app.UIAxes11_2, 'Frame number')
            ylabel(app.UIAxes11_2, 'Intensity')
            app.UIAxes11_2.XLim = [0 100];
            app.UIAxes11_2.YLim = [0 2];
            app.UIAxes11_2.XTick = [0 50 100];
            app.UIAxes11_3 = uiaxes(app.RightPanel);
            title(app.UIAxes11_3, 'Nucleus')
            xlabel(app.UIAxes11_3, 'Frame number')
            ylabel(app.UIAxes11_3, 'Intensity')
            app.UIAxes11_3.XLim = [0 100];
            app.UIAxes11_3.YLim = [0 2];
            app.UIAxes11_3.XTick = [0 50 100];
            app.UIAxes11_4 = uiaxes(app.RightPanel);
            title(app.UIAxes11_4, 'Total')
            xlabel(app.UIAxes11_4, 'Frame number')
            ylabel(app.UIAxes11_4, 'Intensity')
            app.UIAxes11_4.XLim = [0 100];
            app.UIAxes11_4.YLim = [0 2];
            app.UIAxes11_4.XTick = [0 50 100];
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'No. of intervals'; 'Interval'; 'Cell percentile'; 'Mitochondria percentile'; 'Nucleus Percentile'; 'Cell threshold'; 'Mitochondria threshold'; 'Nucleus threshold'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.opto_analyserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = opto_analyser_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.opto_analyserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.opto_analyserUIFigure)
        end
    end
end